classdef Project_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        ClearButton                     matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        InputDataTab                    matlab.ui.container.Tab
        StatusMessageLabel              matlab.ui.control.Label
        StatusMessage                   matlab.ui.control.TextArea
        DHParametersStandardPanel       matlab.ui.container.Panel
        theta6                          matlab.ui.control.EditField
        theta5                          matlab.ui.control.EditField
        theta4                          matlab.ui.control.EditField
        theta3                          matlab.ui.control.EditField
        theta2                          matlab.ui.control.EditField
        theta1                          matlab.ui.control.EditField
        maxtheta6                       matlab.ui.control.NumericEditField
        maxtheta5                       matlab.ui.control.NumericEditField
        maxtheta4                       matlab.ui.control.NumericEditField
        maxtheta3                       matlab.ui.control.NumericEditField
        maxtheta2                       matlab.ui.control.NumericEditField
        maxtheta1                       matlab.ui.control.NumericEditField
        mintheta6                       matlab.ui.control.NumericEditField
        mintheta5                       matlab.ui.control.NumericEditField
        mintheta4                       matlab.ui.control.NumericEditField
        mintheta3                       matlab.ui.control.NumericEditField
        mintheta2                       matlab.ui.control.NumericEditField
        mintheta1                       matlab.ui.control.NumericEditField
        LinkRotationAngleLimitsindegreesminmaxLabel  matlab.ui.control.Label
        alpha6                          matlab.ui.control.NumericEditField
        alpha5                          matlab.ui.control.NumericEditField
        alpha4                          matlab.ui.control.NumericEditField
        alpha3                          matlab.ui.control.NumericEditField
        alpha2                          matlab.ui.control.NumericEditField
        alpha1                          matlab.ui.control.NumericEditField
        a6                              matlab.ui.control.NumericEditField
        a5                              matlab.ui.control.NumericEditField
        a4                              matlab.ui.control.NumericEditField
        a3                              matlab.ui.control.NumericEditField
        a2                              matlab.ui.control.NumericEditField
        a1                              matlab.ui.control.NumericEditField
        d6                              matlab.ui.control.NumericEditField
        d5                              matlab.ui.control.NumericEditField
        d4                              matlab.ui.control.NumericEditField
        d3                              matlab.ui.control.NumericEditField
        d2                              matlab.ui.control.NumericEditField
        d1                              matlab.ui.control.NumericEditField
        linknum6                        matlab.ui.control.NumericEditField
        linknum5                        matlab.ui.control.NumericEditField
        linknum4                        matlab.ui.control.NumericEditField
        linknum3                        matlab.ui.control.NumericEditField
        linknum2                        matlab.ui.control.NumericEditField
        linknum1                        matlab.ui.control.NumericEditField
        LinkLengthainmLabel             matlab.ui.control.Label
        LinkTwistAnglealphaindegLabel   matlab.ui.control.Label
        LinkOffsetdinmLabel             matlab.ui.control.Label
        LinkRotationAnglethetaindegLabel  matlab.ui.control.Label
        LinkNoLabel                     matlab.ui.control.Label
        SelectaSerialRobotDropDown      matlab.ui.control.DropDown
        SelectaSerialRobotDropDownLabel  matlab.ui.control.Label
        RobotAnalysisTab                matlab.ui.container.Tab
        nzLabel                         matlab.ui.control.Label
        nyLabel                         matlab.ui.control.Label
        nxLabel                         matlab.ui.control.Label
        nyval                           matlab.ui.control.NumericEditField
        nzval                           matlab.ui.control.NumericEditField
        nxval                           matlab.ui.control.NumericEditField
        MomentLabel                     matlab.ui.control.Label
        FzLabel                         matlab.ui.control.Label
        FyLabel                         matlab.ui.control.Label
        FxLabel                         matlab.ui.control.Label
        fyval                           matlab.ui.control.NumericEditField
        fzval                           matlab.ui.control.NumericEditField
        fxval                           matlab.ui.control.NumericEditField
        ForceLabel                      matlab.ui.control.Label
        RobotAnalysisPanel              matlab.ui.container.Panel
        Tor1Label_3                     matlab.ui.control.Label
        torque1                         matlab.ui.control.NumericEditField
        torque2                         matlab.ui.control.NumericEditField
        Tor2Label_3                     matlab.ui.control.Label
        Tor5Label_3                     matlab.ui.control.Label
        Tor3Label_3                     matlab.ui.control.Label
        torque3                         matlab.ui.control.NumericEditField
        Tor4Label_3                     matlab.ui.control.Label
        torque4                         matlab.ui.control.NumericEditField
        torque5                         matlab.ui.control.NumericEditField
        Tor6Label_3                     matlab.ui.control.Label
        torque6                         matlab.ui.control.NumericEditField
        DynamicAnalysisButton           matlab.ui.control.Button
        StaticAnalysisButton            matlab.ui.control.Button
        WorkspaceButton                 matlab.ui.control.Button
        q6Label_3                       matlab.ui.control.Label
        q5Label_3                       matlab.ui.control.Label
        q4Label_3                       matlab.ui.control.Label
        q3Label_3                       matlab.ui.control.Label
        q2Label_3                       matlab.ui.control.Label
        q1Label_3                       matlab.ui.control.Label
        InvKinq6                        matlab.ui.control.NumericEditField
        InvKinq5                        matlab.ui.control.NumericEditField
        InvKinq4                        matlab.ui.control.NumericEditField
        InvKinq3                        matlab.ui.control.NumericEditField
        InvKinq2                        matlab.ui.control.NumericEditField
        InvKinq1                        matlab.ui.control.NumericEditField
        ZLabel_2                        matlab.ui.control.Label
        YLabel_2                        matlab.ui.control.Label
        XLabel_2                        matlab.ui.control.Label
        ForKinY                         matlab.ui.control.NumericEditField
        ForKinZ                         matlab.ui.control.NumericEditField
        ForKinX                         matlab.ui.control.NumericEditField
        TraceTrajectoryButton           matlab.ui.control.Button
        InverseKinematicsButton         matlab.ui.control.Button
        BuildRobotButton                matlab.ui.control.Button
        ForwardKinematicsButton         matlab.ui.control.Button
        SetTimeSlider                   matlab.ui.control.Slider
        SetTimeSliderLabel              matlab.ui.control.Label
        RobotPlotPanel                  matlab.ui.container.Panel
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        ZLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        t_fLabel                        matlab.ui.control.Label
        q6Label_2                       matlab.ui.control.Label
        q5Label_2                       matlab.ui.control.Label
        q4Label_2                       matlab.ui.control.Label
        q3Label_2                       matlab.ui.control.Label
        q2Label_2                       matlab.ui.control.Label
        q1Label_2                       matlab.ui.control.Label
        q6Label                         matlab.ui.control.Label
        q5Label                         matlab.ui.control.Label
        q4Label                         matlab.ui.control.Label
        q3Label                         matlab.ui.control.Label
        q2Label                         matlab.ui.control.Label
        q1Label                         matlab.ui.control.Label
        FinalPositionEEY                matlab.ui.control.NumericEditField
        FinalPositionEEZ                matlab.ui.control.NumericEditField
        FinalPositionEEX                matlab.ui.control.NumericEditField
        FinalPositionEELabel            matlab.ui.control.Label
        finaltime                       matlab.ui.control.NumericEditField
        RestPositionq0EditFieldLabel_3  matlab.ui.control.Label
        FinalPositionq2                 matlab.ui.control.NumericEditField
        FinalPositionq3                 matlab.ui.control.NumericEditField
        FinalPositionq4                 matlab.ui.control.NumericEditField
        FinalPositionq5                 matlab.ui.control.NumericEditField
        FinalPositionq6                 matlab.ui.control.NumericEditField
        FinalPositionq1                 matlab.ui.control.NumericEditField
        FinalPositionqLabel             matlab.ui.control.Label
        RestPositionq6                  matlab.ui.control.NumericEditField
        RestPositionq5                  matlab.ui.control.NumericEditField
        RestPositionq4                  matlab.ui.control.NumericEditField
        RestPositionq3                  matlab.ui.control.NumericEditField
        RestPositionq2                  matlab.ui.control.NumericEditField
        RestPositionq1                  matlab.ui.control.NumericEditField
        RestPositionqLabel              matlab.ui.control.Label
        HandMovementTab                 matlab.ui.container.Tab
        timerEditField                  matlab.ui.control.NumericEditField
        StatusMessageLabel_2            matlab.ui.control.Label
        StatusMessage_2                 matlab.ui.control.TextArea
        TimetoRunsecondsEditField       matlab.ui.control.NumericEditField
        TimetoRunsecondsEditFieldLabel  matlab.ui.control.Label
        StartSimulationButton           matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        SolidModelsTab                  matlab.ui.container.Tab
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        StartButton                     matlab.ui.control.Button
        FinishedPartFileEditField       matlab.ui.control.EditField
        FinishedPartFileEditFieldLabel  matlab.ui.control.Label
        WorkpieceFileEditField          matlab.ui.control.EditField
        WorkpieceFileEditFieldLabel     matlab.ui.control.Label
        UIAxes3_3                       matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        ImageRecognitionTab             matlab.ui.container.Tab
        ImageFileEditField              matlab.ui.control.EditField
        ImageFileEditFieldLabel         matlab.ui.control.Label
        StartButton_2                   matlab.ui.control.Button
        UIAxes3_2                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Image.Visible = 'off';
            app.Image_2.Visible = 'off';
            app.UIAxes_2.Visible = 'off';
        end

        % Value changed function: SelectaSerialRobotDropDown
        function SelectaSerialRobotDropDownValueChanged(app, event)
            value = app.SelectaSerialRobotDropDown.Value;
            if value == 1
                app.theta6.Value = num2str('q6');
                app.d1.Value = 0; app.d2.Value = 0; app.d3.Value = 0.15; app.d4.Value = 0.4318; app.d5.Value = 0; app.d6.Value = 0;
                app.a1.Value = 0; app.a2.Value = 0; app.a3.Value = 0.4318; app.a4.Value = 0.0203; app.a5.Value = 0; app.a6.Value = 0;
                app.alpha1.Value = 90; app.alpha2.Value = 0; app.alpha3.Value = -90; app.alpha4.Value = 90; app.alpha5.Value = -90; app.alpha6.Value = 0;
                app.mintheta1.Value = -160; app.mintheta2.Value = -225; app.mintheta3.Value = -45; app.mintheta4.Value = -110; app.mintheta5.Value = -100; app.mintheta6.Value = -266;
                app.maxtheta1.Value = +160; app.maxtheta2.Value = +45; app.maxtheta3.Value = +225; app.maxtheta4.Value = +110; app.maxtheta5.Value = +100; app.maxtheta6.Value = +266;
            elseif value == 2
                app.theta6.Value = num2str('q6');
                app.d1.Value = 0.475; app.d2.Value = 0; app.d3.Value = 0; app.d4.Value = 0.720; app.d5.Value = 0; app.d6.Value = 0.085;
                app.a1.Value = 0; app.a2.Value = 0.150; app.a3.Value = 0.600; app.a4.Value = 0.120; app.a5.Value = 0; app.a6.Value = 0;
                app.alpha1.Value = 0; app.alpha2.Value = 90; app.alpha3.Value = 0; app.alpha4.Value = 90; app.alpha5.Value = -90; app.alpha6.Value = 90;
                app.mintheta1.Value = -170; app.mintheta2.Value = -70; app.mintheta3.Value = -65; app.mintheta4.Value = -150; app.mintheta5.Value = -115; app.mintheta6.Value = -300;
                app.maxtheta1.Value = +170; app.maxtheta2.Value = +70; app.maxtheta3.Value = +70; app.maxtheta4.Value = +150; app.maxtheta5.Value = +115; app.maxtheta6.Value = +300;
            elseif value == 3
                app.theta6.Value = num2str('q6');
                app.d1.Value = 0.317; app.d2.Value = 0; app.d3.Value = 0.045; app.d4.Value = 0; app.d5.Value = 0.480; app.d6.Value = 0;
                app.a1.Value = 0; app.a2.Value = 0; app.a3.Value = 0; app.a4.Value = 0; app.a5.Value = 0; app.a6.Value = 0;
                app.alpha1.Value = -90; app.alpha2.Value = 90; app.alpha3.Value = -90; app.alpha4.Value = 90; app.alpha5.Value = -90; app.alpha6.Value = 90;
                app.mintheta1.Value = -180; app.mintheta2.Value = -101.05; app.mintheta3.Value = -180; app.mintheta4.Value = -153.73; app.mintheta5.Value = -270; app.mintheta6.Value = -180;
                app.maxtheta1.Value = +180; app.maxtheta2.Value = +101.05; app.maxtheta3.Value = +180; app.maxtheta4.Value = +153.73; app.maxtheta5.Value = +270; app.maxtheta6.Value = +180;
            elseif value == 4
                app.theta6.Value = num2str(0);
                app.d1.Value = 0.150; app.d2.Value = 0; app.d3.Value = 0; app.d4.Value = 0.125; app.d5.Value = 0; app.d6.Value = 0.130;
                app.a1.Value = 0.060; app.a2.Value = 0.145; app.a3.Value = 0; app.a4.Value = 0; app.a5.Value = 0; app.a6.Value = 0;
                app.alpha1.Value = -90; app.alpha2.Value = 0; app.alpha3.Value = -90; app.alpha4.Value = 90; app.alpha5.Value = -90; app.alpha6.Value = 0;
                app.mintheta1.Value = -180; app.mintheta2.Value = -180; app.mintheta3.Value = -180; app.mintheta4.Value = -180; app.mintheta5.Value = -180; app.mintheta6.Value = 0;
                app.maxtheta1.Value = +180; app.maxtheta2.Value = +180; app.maxtheta3.Value = +180; app.maxtheta4.Value = +180; app.maxtheta5.Value = +180; app.maxtheta6.Value = 0;
            elseif value == 5
                app.theta6.Value = num2str(0);
                app.d1.Value = 0.070; app.d2.Value = 0; app.d3.Value = 0; app.d4.Value = 0; app.d5.Value = 0.095; app.d6.Value = 0;
                app.a1.Value = 0; app.a2.Value = 0.450; app.a3.Value = 0.670; app.a4.Value = 0; app.a5.Value = 0; app.a6.Value = 0;
                app.alpha1.Value = 90; app.alpha2.Value = 0; app.alpha3.Value = 0; app.alpha4.Value = 90; app.alpha5.Value = 0; app.alpha6.Value = 0;
                app.mintheta1.Value = -130; app.mintheta2.Value = -130; app.mintheta3.Value = -220; app.mintheta4.Value = -153.73; app.mintheta5.Value = 0; app.mintheta6.Value = 0;
                app.maxtheta1.Value = +90; app.maxtheta2.Value = -50; app.maxtheta3.Value = -25; app.maxtheta4.Value = +153.73; app.maxtheta5.Value = +360; app.maxtheta6.Value = 0;

            end
        end

        % Button pushed function: BuildRobotButton
        function BuildRobotButtonPushed(app, event)
            app.UIAxes_2.Visible = 'on';
            %Link Parameters INPUT
            q1r = app.RestPositionq1.Value; q2r = app.RestPositionq2.Value; q3r = app.RestPositionq3.Value; q4r = app.RestPositionq4.Value; q5r = app.RestPositionq5.Value; q6r = app.RestPositionq6.Value;
            q1f = app.FinalPositionq1.Value; q2f = app.FinalPositionq2.Value; q3f = app.FinalPositionq3.Value; q4f = app.FinalPositionq4.Value; q5f = app.FinalPositionq5.Value; q6f = app.FinalPositionq6.Value;
            tf = app.finaltime.Value;
            x = app.FinalPositionEEX.Value; y = app.FinalPositionEEY.Value; z = app.FinalPositionEEZ.Value;

            q6check = app.theta6.Value;
            a1v = app.a1.Value;  a2v = app.a2.Value; a3v = app.a3.Value; a4v = app.a4.Value; a5v = app.a5.Value; a6v = app.a6.Value;
            d1v = app.d1.Value;  d2v = app.d2.Value; d3v = app.d3.Value; d4v = app.d4.Value; d5v = app.d5.Value; d6v = app.d6.Value;
            alp1 = app.alpha1.Value;alp2 = app.alpha2.Value;alp3 = app.alpha3.Value;alp4 = app.alpha4.Value;alp5 = app.alpha5.Value;alp6 = app.alpha6.Value;
            q1min = app.mintheta1.Value;q2min = app.mintheta2.Value;q3min = app.mintheta3.Value;q4min = app.mintheta4.Value;q5min = app.mintheta5.Value;q6min = app.mintheta6.Value;
            q1max = app.maxtheta1.Value;q2max = app.maxtheta2.Value;q3max = app.maxtheta3.Value;q4max = app.maxtheta4.Value;q5max = app.maxtheta5.Value;q6max = app.maxtheta6.Value;
            Robonameval = string(app.SelectaSerialRobotDropDown.Value);
            if Robonameval == string(1)
                Roboname = '6-DOF PUMA 560';
            elseif Robonameval == string(2)
                Roboname = '6-DOF ABB IRb-1400';
            elseif Robonameval == string(3)
                Roboname = '6-DOF STAUBLI RX160L';
            elseif Robonameval == string(4)
                Roboname = '5-DOF PIONEER-2';
            elseif Robonameval == string(5)
                Roboname = '5-DOF ASEA IRb-6';
            end

            %DH Parameters
            %Standard or Classic
            if q6check ~= 0
                L(1) = Link([0 d1v a1v deg2rad(alp1)],'standard');
                L(2) = Link([0 d2v a2v deg2rad(alp2)],'standard');
                L(3) = Link([0 d3v a3v deg2rad(alp3)],'standard');
                L(4) = Link([0 d4v a4v deg2rad(alp4)],'standard');
                L(5) = Link([0 d5v a5v deg2rad(alp5)],'standard');
                L(6) = Link([0 d6v a6v deg2rad(alp6)],'standard');

                %Link Movement Limits in terms of Joint angle variables
                L(1).qlim = [deg2rad(q1min) deg2rad(q1max)];
                L(2).qlim = [deg2rad(q2min) deg2rad(q2max)];
                L(3).qlim = [deg2rad(q3min) deg2rad(q3max)];
                L(4).qlim = [deg2rad(q4min) deg2rad(q4max)];
                L(5).qlim = [deg2rad(q5min) deg2rad(q5max)];
                L(6).qlim = [deg2rad(q6min) deg2rad(q6max)];

                % Rest Position
                q0 = [deg2rad(q1r) deg2rad(q2r) deg2rad(q3r) deg2rad(q4r) deg2rad(q5r) deg2rad(q6r)];
                %Final Position
                qf = [deg2rad(q1f) deg2rad(q2f) deg2rad(q3f) deg2rad(q4f) deg2rad(q5f) deg2rad(q6f)];

                %Build the Robot
                ROBO = SerialLink(L);
                ROBO.name = Roboname;
                ax1 = app.UIAxes;
                ROBO.plot(qf);

            elseif q6check ==0
                L(1) = Link([0 d1v a1v deg2rad(alp1)],'standard');
                L(2) = Link([0 d2v a2v deg2rad(alp2)],'standard');
                L(3) = Link([0 d3v a3v deg2rad(alp3)],'standard');
                L(4) = Link([0 d4v a4v deg2rad(alp4)],'standard');
                L(5) = Link([0 d5v a5v deg2rad(alp5)],'standard');

                %Link Movement Limits in terms of Joint angle variables
                L(1).qlim = [deg2rad(q1min) deg2rad(q1max)];
                L(2).qlim = [deg2rad(q2min) deg2rad(q2max)];
                L(3).qlim = [deg2rad(q3min) deg2rad(q3max)];
                L(4).qlim = [deg2rad(q4min) deg2rad(q4max)];
                L(5).qlim = [deg2rad(q5min) deg2rad(q5max)];

                % Rest Position
                q0 = [deg2rad(q1r) deg2rad(q2r) deg2rad(q3r) deg2rad(q4r) deg2rad(q5r)];
                %Final Position
                qf = [deg2rad(q1f) deg2rad(q2f) deg2rad(q3f) deg2rad(q4f) deg2rad(q5f)];

                %Build the Robot
                ROBO = SerialLink(L);
                ROBO.name = Roboname;
                ax1 = app.UIAxes;
                ROBO.plot(qf);
            end

        end

        % Value changing function: SetTimeSlider
        function SetTimeSliderValueChanging(app, event)
            changingValue = event.Value;
            global n;
            n = changingValue;
            app.finaltime.Value = round(n);
        end

        % Button pushed function: ForwardKinematicsButton
        function ForwardKinematicsButtonPushed(app, event)
            app.UIAxes_2.reset
            app.UIAxes_2.Visible = 'off';
            %Link Parameters INPUT
            q1r = app.RestPositionq1.Value; q2r = app.RestPositionq2.Value; q3r = app.RestPositionq3.Value; q4r = app.RestPositionq4.Value; q5r = app.RestPositionq5.Value; q6r = app.RestPositionq6.Value;
            q1f = app.FinalPositionq1.Value; q2f = app.FinalPositionq2.Value; q3f = app.FinalPositionq3.Value; q4f = app.FinalPositionq4.Value; q5f = app.FinalPositionq5.Value; q6f = app.FinalPositionq6.Value;
            tf = app.finaltime.Value;
            x = app.FinalPositionEEX.Value; y = app.FinalPositionEEY.Value; z = app.FinalPositionEEZ.Value;

            q6check = app.theta6.Value;
            a1v = app.a1.Value;  a2v = app.a2.Value; a3v = app.a3.Value; a4v = app.a4.Value; a5v = app.a5.Value; a6v = app.a6.Value;
            d1v = app.d1.Value;  d2v = app.d2.Value; d3v = app.d3.Value; d4v = app.d4.Value; d5v = app.d5.Value; d6v = app.d6.Value;
            alp1 = app.alpha1.Value;alp2 = app.alpha2.Value;alp3 = app.alpha3.Value;alp4 = app.alpha4.Value;alp5 = app.alpha5.Value;alp6 = app.alpha6.Value;
            q1min = app.mintheta1.Value;q2min = app.mintheta2.Value;q3min = app.mintheta3.Value;q4min = app.mintheta4.Value;q5min = app.mintheta5.Value;q6min = app.mintheta6.Value;
            q1max = app.maxtheta1.Value;q2max = app.maxtheta2.Value;q3max = app.maxtheta3.Value;q4max = app.maxtheta4.Value;q5max = app.maxtheta5.Value;q6max = app.maxtheta6.Value;
            Robonameval = string(app.SelectaSerialRobotDropDown.Value);
            if Robonameval == string(1)
                Roboname = '6-DOF PUMA 560';
            elseif Robonameval == string(2)
                Roboname = '6-DOF ABB IRb-1400';
            elseif Robonameval == string(3)
                Roboname = '6-DOF STAUBLI RX160L';
            elseif Robonameval == string(4)
                Roboname = '5-DOF PIONEER-2';
            elseif Robonameval == string(5)
                Roboname = '5-DOF ASEA IRb-6';
            end

            %DH Parameters
            %Standard or Classic
            if q6check ~= 0
                L(1) = Link([0 d1v a1v deg2rad(alp1)],'standard');
                L(2) = Link([0 d2v a2v deg2rad(alp2)],'standard');
                L(3) = Link([0 d3v a3v deg2rad(alp3)],'standard');
                L(4) = Link([0 d4v a4v deg2rad(alp4)],'standard');
                L(5) = Link([0 d5v a5v deg2rad(alp5)],'standard');
                L(6) = Link([0 d6v a6v deg2rad(alp6)],'standard');

                %Link Movement Limits in terms of Joint angle variables
                L(1).qlim = [deg2rad(q1min) deg2rad(q1max)];
                L(2).qlim = [deg2rad(q2min) deg2rad(q2max)];
                L(3).qlim = [deg2rad(q3min) deg2rad(q3max)];
                L(4).qlim = [deg2rad(q4min) deg2rad(q4max)];
                L(5).qlim = [deg2rad(q5min) deg2rad(q5max)];
                L(6).qlim = [deg2rad(q6min) deg2rad(q6max)];

                % Rest Position
                q0 = [deg2rad(q1r) deg2rad(q2r) deg2rad(q3r) deg2rad(q4r) deg2rad(q5r) deg2rad(q6r)];
                %Final Position
                qf = [deg2rad(q1f) deg2rad(q2f) deg2rad(q3f) deg2rad(q4f) deg2rad(q5f) deg2rad(q6f)];

                %Build the Robot
                ROBO = SerialLink(L);
                ROBO.name = Roboname;
                ax1 = app.UIAxes;

                % Forward Kinematics
                Tf = ROBO.fkine(qf); %Transformation matrix from Forward Kinematics
                trs = transl(Tf); %Takes the last column of Translation Matrix of T
                app.ForKinX.Value = trs(1);
                app.ForKinY.Value = trs(2);
                app.ForKinZ.Value = trs(3);


                %Trajectory
                t = 0:0.15:tf; %Time 0 to 5 seconds in the increment of 0.15 INPUT
                Q = jtraj(q0,qf,t); %Create Joint Angles from initial to final joint configuration
                Tr = ROBO.fkine(Q);

                %To show the trajectory of the robot
                for i = 1:1:length(t)
                    T = Tr(i);
                    trs = transl(T); %Takes the last column of Translation Matrix of T
                    xx(i) = trs(1);
                    yy(i) = trs(2);
                    zz(i) = trs(3);
                end
                cla(ax1);
                plot3(ax1,xx,yy,zz,'color',[1 0 0],'LineWidth',2);
                grid on;

                plot3(xx,yy,zz,'color',[1 0 0],'LineWidth',2); hold on;
                plot(ROBO,Q);
                hold off;


            elseif q6check ==0
                L(1) = Link([0 d1v a1v deg2rad(alp1)],'standard');
                L(2) = Link([0 d2v a2v deg2rad(alp2)],'standard');
                L(3) = Link([0 d3v a3v deg2rad(alp3)],'standard');
                L(4) = Link([0 d4v a4v deg2rad(alp4)],'standard');
                L(5) = Link([0 d5v a5v deg2rad(alp5)],'standard');

                %Link Movement Limits in terms of Joint angle variables
                L(1).qlim = [deg2rad(q1min) deg2rad(q1max)];
                L(2).qlim = [deg2rad(q2min) deg2rad(q2max)];
                L(3).qlim = [deg2rad(q3min) deg2rad(q3max)];
                L(4).qlim = [deg2rad(q4min) deg2rad(q4max)];
                L(5).qlim = [deg2rad(q5min) deg2rad(q5max)];

                % Rest Position
                q0 = [deg2rad(q1r) deg2rad(q2r) deg2rad(q3r) deg2rad(q4r) deg2rad(q5r)];
                %Final Position
                qf = [deg2rad(q1f) deg2rad(q2f) deg2rad(q3f) deg2rad(q4f) deg2rad(q5f)];

                %Build the Robot
                ROBO = SerialLink(L);
                ROBO.name = Roboname;
                ax1 = app.UIAxes;

                % Forward Kinematics
                Tf = ROBO.fkine(qf); %Transformation matrix from Forward Kinematics
                trs = transl(Tf); %Takes the last column of Translation Matrix of T
                app.ForKinX.Value = round(trs(1),4);
                app.ForKinY.Value = round(trs(2),4);
                app.ForKinZ.Value = round(trs(3),4);

                %Trajectory
                t = 0:0.15:tf; %Time 0 to 5 seconds in the increment of 0.15 INPUT
                Q = jtraj(q0,qf,t); %Create Joint Angles from initial to final joint configuration
                Tr = ROBO.fkine(Q);

                %To show the trajectory of the robot
                for i = 1:1:length(t)
                    T = Tr(i);
                    trs = transl(T); %Takes the last column of Translation Matrix of T
                    xx(i) = trs(1);
                    yy(i) = trs(2);
                    zz(i) = trs(3);
                end
                cla(ax1);
                plot3(ax1,xx,yy,zz,'color',[1 0 0],'LineWidth',2);
                grid on;

                plot3(xx,yy,zz,'color',[1 0 0],'LineWidth',2); hold on;
                plot(ROBO,Q);
                hold off;

            end

        end

        % Button pushed function: InverseKinematicsButton
        function InverseKinematicsButtonPushed(app, event)
            app.UIAxes_2.reset
            app.UIAxes_2.Visible = 'off';
            %Link Parameters INPUT
            q1r = app.RestPositionq1.Value; q2r = app.RestPositionq2.Value; q3r = app.RestPositionq3.Value; q4r = app.RestPositionq4.Value; q5r = app.RestPositionq5.Value; q6r = app.RestPositionq6.Value;
            q1f = app.FinalPositionq1.Value; q2f = app.FinalPositionq2.Value; q3f = app.FinalPositionq3.Value; q4f = app.FinalPositionq4.Value; q5f = app.FinalPositionq5.Value; q6f = app.FinalPositionq6.Value;
            tf = app.finaltime.Value;
            X = app.FinalPositionEEX.Value; Y = app.FinalPositionEEY.Value; Z=app.FinalPositionEEZ.Value;

            q6check = app.theta6.Value;
            a1v = app.a1.Value;  a2v = app.a2.Value; a3v = app.a3.Value; a4v = app.a4.Value; a5v = app.a5.Value; a6v = app.a6.Value;
            d1v = app.d1.Value;  d2v = app.d2.Value; d3v = app.d3.Value; d4v = app.d4.Value; d5v = app.d5.Value; d6v = app.d6.Value;
            alp1 = app.alpha1.Value;alp2 = app.alpha2.Value;alp3 = app.alpha3.Value;alp4 = app.alpha4.Value;alp5 = app.alpha5.Value;alp6 = app.alpha6.Value;
            q1min = app.mintheta1.Value;q2min = app.mintheta2.Value;q3min = app.mintheta3.Value;q4min = app.mintheta4.Value;q5min = app.mintheta5.Value;q6min = app.mintheta6.Value;
            q1max = app.maxtheta1.Value;q2max = app.maxtheta2.Value;q3max = app.maxtheta3.Value;q4max = app.maxtheta4.Value;q5max = app.maxtheta5.Value;q6max = app.maxtheta6.Value;
            Robonameval = string(app.SelectaSerialRobotDropDown.Value);
            if Robonameval == string(1)
                Roboname = '6-DOF PUMA 560';
            elseif Robonameval == string(2)
                Roboname = '6-DOF ABB IRb-1400';
            elseif Robonameval == string(3)
                Roboname = '6-DOF STAUBLI RX160L';
            elseif Robonameval == string(4)
                Roboname = '5-DOF PIONEER-2';
            elseif Robonameval == string(5)
                Roboname = '5-DOF ASEA IRb-6';
            end

            %DH Parameters
            %Standard or Classic
            if q6check ~= 0
                L(1) = Link([0 d1v a1v deg2rad(alp1)],'standard');
                L(2) = Link([0 d2v a2v deg2rad(alp2)],'standard');
                L(3) = Link([0 d3v a3v deg2rad(alp3)],'standard');
                L(4) = Link([0 d4v a4v deg2rad(alp4)],'standard');
                L(5) = Link([0 d5v a5v deg2rad(alp5)],'standard');
                L(6) = Link([0 d6v a6v deg2rad(alp6)],'standard');

                %Link Movement Limits in terms of Joint angle variables
                L(1).qlim = [deg2rad(q1min) deg2rad(q1max)];
                L(2).qlim = [deg2rad(q2min) deg2rad(q2max)];
                L(3).qlim = [deg2rad(q3min) deg2rad(q3max)];
                L(4).qlim = [deg2rad(q4min) deg2rad(q4max)];
                L(5).qlim = [deg2rad(q5min) deg2rad(q5max)];
                L(6).qlim = [deg2rad(q6min) deg2rad(q6max)];

                % Rest Position
                q0 = [deg2rad(q1r) deg2rad(q2r) deg2rad(q3r) deg2rad(q4r) deg2rad(q5r) deg2rad(q6r)];
                %Final Position
                qf = [deg2rad(q1f) deg2rad(q2f) deg2rad(q3f) deg2rad(q4f) deg2rad(q5f) deg2rad(q6f)];

                %Build the Robot
                ROBO = SerialLink(L);
                ROBO.name = Roboname;
                ax1 = app.UIAxes;

                qf = [1.0694+X 0.0637+Y -0.9054+Z 0.0000+X 0.8417+Y -1.0694+Z];
                Tf = ROBO.fkine(qf); %Transformation matrix from Forward Kinematics

                %Position in space
                %                 Tf.t(1,1) = 0.1;
                %                 Tf.t(2,1) = 0.2;
                %                 Tf.t(3,1) = 0.01;

                %Inverse Kinematics
                q0 = [0 0 0 0 0 0]; %Initial Joint Configuration INPUT
                qinv = ROBO.ikine(Tf,q0,'mask',[1 1 1 1 1 1]); %Inverse Kinematics
                ROBO.plot(qinv);
                app.InvKinq1.Value = round(rad2deg(qinv(1)),2);
                app.InvKinq2.Value = round(rad2deg(qinv(2)),2);
                app.InvKinq3.Value = round(rad2deg(qinv(3)),2);
                app.InvKinq4.Value = round(rad2deg(qinv(4)),2);
                app.InvKinq5.Value = round(rad2deg(qinv(5)),2);
                app.InvKinq6.Value = round(rad2deg(qinv(6)),2);

            elseif q6check ==0
                L(1) = Link([0 d1v a1v deg2rad(alp1)],'standard');
                L(2) = Link([0 d2v a2v deg2rad(alp2)],'standard');
                L(3) = Link([0 d3v a3v deg2rad(alp3)],'standard');
                L(4) = Link([0 d4v a4v deg2rad(alp4)],'standard');
                L(5) = Link([0 d5v a5v deg2rad(alp5)],'standard');

                %Link Movement Limits in terms of Joint angle variables
                L(1).qlim = [deg2rad(q1min) deg2rad(q1max)];
                L(2).qlim = [deg2rad(q2min) deg2rad(q2max)];
                L(3).qlim = [deg2rad(q3min) deg2rad(q3max)];
                L(4).qlim = [deg2rad(q4min) deg2rad(q4max)];
                L(5).qlim = [deg2rad(q5min) deg2rad(q5max)];

                % Rest Position
                q0 = [deg2rad(q1r) deg2rad(q2r) deg2rad(q3r) deg2rad(q4r) deg2rad(q5r)];
                %Final Position
                qf = [50*x*deg2rad(q1f) 40*y*deg2rad(q2f) 20*z*deg2rad(q3f) 60*x*deg2rad(q4f) 80*y*deg2rad(q5f)];

                %Build the Robot
                ROBO = SerialLink(L);
                ROBO.name = Roboname;
                ax1 = app.UIAxes;

                % Forward Kinematics
                qf = [1.0694+X 0.0637+Y -0.9054+Z 0.0000+X 0.8417+Y];
                Tf = ROBO.fkine(qf); %Transformation matrix from Forward Kinematics

                %Position in space
                %                 Tf.t(1,1)=x;
                %                 Tf.t(2,1)=y;
                %                 Tf.t(3,1)=z;

                %Inverse Kinematics
                q0 = [0 0 0 0 0]; %Initial Joint Configuration INPUT
                qinv = ROBO.ikine(Tf,q0,'mask',[1 1 1 1 1 0]); %Inverse Kinematics
                ROBO.plot(qinv);
                app.InvKinq1.Value = round(rad2deg(qinv(1)),2);
                app.InvKinq2.Value = round(rad2deg(qinv(2)),2);
                app.InvKinq3.Value = round(rad2deg(qinv(3)),2);
                app.InvKinq4.Value = round(rad2deg(qinv(4)),2);
                app.InvKinq5.Value = round(rad2deg(qinv(5)),2);
            end
        end

        % Button pushed function: TraceTrajectoryButton
        function TraceTrajectoryButtonPushed(app, event)
            app.UIAxes_2.reset
            app.UIAxes_2.Visible = 'off';
            %Link Parameters INPUT
            q1r = app.RestPositionq1.Value; q2r = app.RestPositionq2.Value; q3r = app.RestPositionq3.Value; q4r = app.RestPositionq4.Value; q5r = app.RestPositionq5.Value; q6r = app.RestPositionq6.Value;
            q1f = app.FinalPositionq1.Value; q2f = app.FinalPositionq2.Value; q3f = app.FinalPositionq3.Value; q4f = app.FinalPositionq4.Value; q5f = app.FinalPositionq5.Value; q6f = app.FinalPositionq6.Value;
            tf = app.finaltime.Value;
            x = app.FinalPositionEEX.Value; y = app.FinalPositionEEY.Value; z = app.FinalPositionEEZ.Value;

            q6check = app.theta6.Value;
            a1v = app.a1.Value;  a2v = app.a2.Value; a3v = app.a3.Value; a4v = app.a4.Value; a5v = app.a5.Value; a6v = app.a6.Value;
            d1v = app.d1.Value;  d2v = app.d2.Value; d3v = app.d3.Value; d4v = app.d4.Value; d5v = app.d5.Value; d6v = app.d6.Value;
            alp1 = app.alpha1.Value;alp2 = app.alpha2.Value;alp3 = app.alpha3.Value;alp4 = app.alpha4.Value;alp5 = app.alpha5.Value;alp6 = app.alpha6.Value;
            q1min = app.mintheta1.Value;q2min = app.mintheta2.Value;q3min = app.mintheta3.Value;q4min = app.mintheta4.Value;q5min = app.mintheta5.Value;q6min = app.mintheta6.Value;
            q1max = app.maxtheta1.Value;q2max = app.maxtheta2.Value;q3max = app.maxtheta3.Value;q4max = app.maxtheta4.Value;q5max = app.maxtheta5.Value;q6max = app.maxtheta6.Value;
            Robonameval = string(app.SelectaSerialRobotDropDown.Value);
            if Robonameval == string(1)
                Roboname = '6-DOF PUMA 560';
            elseif Robonameval == string(2)
                Roboname = '6-DOF ABB IRb-1400';
            elseif Robonameval == string(3)
                Roboname = '6-DOF STAUBLI RX160L';
            elseif Robonameval == string(4)
                Roboname = '5-DOF PIONEER-2';
            elseif Robonameval == string(5)
                Roboname = '5-DOF ASEA IRb-6';
            end

            %DH Parameters
            %Standard or Classic
            if q6check ~= 0
                L(1) = Link([0 d1v a1v deg2rad(alp1)],'standard');
                L(2) = Link([0 d2v a2v deg2rad(alp2)],'standard');
                L(3) = Link([0 d3v a3v deg2rad(alp3)],'standard');
                L(4) = Link([0 d4v a4v deg2rad(alp4)],'standard');
                L(5) = Link([0 d5v a5v deg2rad(alp5)],'standard');
                L(6) = Link([0 d6v a6v deg2rad(alp6)],'standard');

                %Link Movement Limits in terms of Joint angle variables
                L(1).qlim = [deg2rad(q1min) deg2rad(q1max)];
                L(2).qlim = [deg2rad(q2min) deg2rad(q2max)];
                L(3).qlim = [deg2rad(q3min) deg2rad(q3max)];
                L(4).qlim = [deg2rad(q4min) deg2rad(q4max)];
                L(5).qlim = [deg2rad(q5min) deg2rad(q5max)];
                L(6).qlim = [deg2rad(q6min) deg2rad(q6max)];

                % Rest Position
                q0 = [deg2rad(q1r) deg2rad(q2r) deg2rad(q3r) deg2rad(q4r) deg2rad(q5r) deg2rad(q6r)];
                %Final Position
                qf = [deg2rad(q1f) deg2rad(q2f) deg2rad(q3f) deg2rad(q4f) deg2rad(q5f) deg2rad(q6f)];

                %Build the Robot
                ROBO = SerialLink(L);
                ROBO.name = Roboname;
                ax1 = app.UIAxes;

                % Forward Kinematics
                Tf = ROBO.fkine(qf); %Transformation matrix from Forward Kinematics

                %Trajectory
                t = 0:0.15:tf; %Time 0 to tf seconds in the increment of 0.15 INPUT
                Q = jtraj(q0,qf,t); %Create Joint Angles from initial to final joint configuration
                Tr = ROBO.fkine(Q);

                %To show the trajectory of the robot
                for i = 1:1:length(t)
                    T = Tr(i);
                    trs = transl(T); %Takes the last column of Translation Matrix of T
                    xx(i) = trs(1);
                    yy(i) = trs(2);
                    zz(i) = trs(3);
                end

                cla(ax1);
                plot3(ax1,xx,yy,zz,'color',[1 0 0],'LineWidth',2);
                plot3(xx,yy,zz,'color',[1 0 0],'LineWidth',2);
                hold on;
                plot(ROBO,Q); hold off;


            elseif q6check ==0
                L(1) = Link([0 d1v a1v deg2rad(alp1)],'standard');
                L(2) = Link([0 d2v a2v deg2rad(alp2)],'standard');
                L(3) = Link([0 d3v a3v deg2rad(alp3)],'standard');
                L(4) = Link([0 d4v a4v deg2rad(alp4)],'standard');
                L(5) = Link([0 d5v a5v deg2rad(alp5)],'standard');

                %Link Movement Limits in terms of Joint angle variables
                L(1).qlim = [deg2rad(q1min) deg2rad(q1max)];
                L(2).qlim = [deg2rad(q2min) deg2rad(q2max)];
                L(3).qlim = [deg2rad(q3min) deg2rad(q3max)];
                L(4).qlim = [deg2rad(q4min) deg2rad(q4max)];
                L(5).qlim = [deg2rad(q5min) deg2rad(q5max)];

                % Rest Position
                q0 = [deg2rad(q1r) deg2rad(q2r) deg2rad(q3r) deg2rad(q4r) deg2rad(q5r)];
                %Final Position
                qf = [deg2rad(q1f) deg2rad(q2f) deg2rad(q3f) deg2rad(q4f) deg2rad(q5f)];

                %Build the Robot
                ROBO = SerialLink(L);
                ROBO.name = Roboname;
                ax1 = app.UIAxes;

                % Forward Kinematics
                Tf = ROBO.fkine(qf); %Transformation matrix from Forward Kinematics

                %Trajectory
                t = 0:0.15:tf; %Time 0 to tf seconds in the increment of 0.15 INPUT
                Q = jtraj(q0,qf,t); %Create Joint Angles from initial to final joint configuration
                Tr = ROBO.fkine(Q);

                %To show the trajectory of the robot
                for i = 1:1:length(t)
                    T = Tr(i);
                    trs = transl(T); %Takes the last column of Translation Matrix of T
                    xx(i) = trs(1);
                    yy(i) = trs(2);
                    zz(i) = trs(3);
                end

                cla(ax1);
                plot3(ax1,xx,yy,zz,'color',[1 0 0],'LineWidth',2);
                plot3(xx,yy,zz,'color',[1 0 0],'LineWidth',2);
                hold on;
                plot(ROBO,Q); hold off;

            end
        end

        % Button pushed function: WorkspaceButton
        function WorkspaceButtonPushed(app, event)
            app.UIAxes_2.reset
            app.UIAxes_2.Visible = 'off';
            %Link Parameters INPUT
            q1r = app.RestPositionq1.Value; q2r = app.RestPositionq2.Value; q3r = app.RestPositionq3.Value; q4r = app.RestPositionq4.Value; q5r = app.RestPositionq5.Value; q6r = app.RestPositionq6.Value;
            q1f = app.FinalPositionq1.Value; q2f = app.FinalPositionq2.Value; q3f = app.FinalPositionq3.Value; q4f = app.FinalPositionq4.Value; q5f = app.FinalPositionq5.Value; q6f = app.FinalPositionq6.Value;
            tf = app.finaltime.Value;
            x = app.FinalPositionEEX.Value; y = app.FinalPositionEEY.Value; z = app.FinalPositionEEZ.Value;

            q6check = app.theta6.Value;
            a1v = app.a1.Value;  a2v = app.a2.Value; a3v = app.a3.Value; a4v = app.a4.Value; a5v = app.a5.Value; a6v = app.a6.Value;
            d1v = app.d1.Value;  d2v = app.d2.Value; d3v = app.d3.Value; d4v = app.d4.Value; d5v = app.d5.Value; d6v = app.d6.Value;
            alp1 = app.alpha1.Value;alp2 = app.alpha2.Value;alp3 = app.alpha3.Value;alp4 = app.alpha4.Value;alp5 = app.alpha5.Value;alp6 = app.alpha6.Value;
            q1min = app.mintheta1.Value;q2min = app.mintheta2.Value;q3min = app.mintheta3.Value;q4min = app.mintheta4.Value;q5min = app.mintheta5.Value;q6min = app.mintheta6.Value;
            q1max = app.maxtheta1.Value;q2max = app.maxtheta2.Value;q3max = app.maxtheta3.Value;q4max = app.maxtheta4.Value;q5max = app.maxtheta5.Value;q6max = app.maxtheta6.Value;
            Robonameval = string(app.SelectaSerialRobotDropDown.Value);
            if Robonameval == string(1)
                Roboname = '6-DOF PUMA 560';
            elseif Robonameval == string(2)
                Roboname = '6-DOF ABB IRb-1400';
            elseif Robonameval == string(3)
                Roboname = '6-DOF STAUBLI RX160L';
            elseif Robonameval == string(4)
                Roboname = '5-DOF PIONEER-2';
            elseif Robonameval == string(5)
                Roboname = '5-DOF ASEA IRb-6';
            end

            %DH Parameters
            %Standard or Classic
            if q6check ~= 0
                L(1) = Link([0 d1v a1v deg2rad(alp1)],'standard');
                L(2) = Link([0 d2v a2v deg2rad(alp2)],'standard');
                L(3) = Link([0 d3v a3v deg2rad(alp3)],'standard');
                L(4) = Link([0 d4v a4v deg2rad(alp4)],'standard');
                L(5) = Link([0 d5v a5v deg2rad(alp5)],'standard');
                L(6) = Link([0 d6v a6v deg2rad(alp6)],'standard');

                %Link Movement Limits in terms of Joint angle variables
                L(1).qlim = [deg2rad(q1min) deg2rad(q1max)];
                L(2).qlim = [deg2rad(q2min) deg2rad(q2max)];
                L(3).qlim = [deg2rad(q3min) deg2rad(q2max)];
                L(4).qlim = [deg2rad(q4min) deg2rad(q4max)];
                L(5).qlim = [deg2rad(q5min) deg2rad(q5max)];
                L(6).qlim = [deg2rad(q6min) deg2rad(q6max)];

                % Rest Position
                q0 = [deg2rad(q1r) deg2rad(q2r) deg2rad(q3r) deg2rad(q4r) deg2rad(q5r) deg2rad(q6r)];
                %Final Position
                qf = [deg2rad(q1f) deg2rad(q2f) deg2rad(q3f) deg2rad(q4f) deg2rad(q5f) deg2rad(q6f)];

                %Build the Robot
                ROBO = SerialLink(L);
                ROBO.name = Roboname;
                ax1 = app.UIAxes;

                %Workspace
                i = 0;
                for q1 = deg2rad(q1min):deg2rad(10):deg2rad(q1max)
                    for q2 = deg2rad(q2min):deg2rad(10):deg2rad(q2max)
                        for q3 = deg2rad(q3min):deg2rad(10):deg2rad(q2max)
                            %             for q4 = deg2rad(-110):deg2rad(10):deg2rad(110)
                            %                 for q5 = deg2rad(-100):deg2rad(10):deg2rad(100)
                            %                     for q6 = deg2rad(-266):deg2rad(10):deg2rad(266)
                            T01 = trotz(q1)*transl(a1v,0,d1v)*trotx(deg2rad(alp1));
                            T12 = trotz(q2)*transl(a2v,0,d2v)*trotx(deg2rad(alp2));
                            T23 = trotz(q3)*transl(a3v,0,d3v)*trotx(deg2rad(alp3));
                            %                         T34 = trotz(q4)*transl(0,0,d4)*trotx(pi/2);
                            %                         T45 = trotz(q5)*transl(0,0,0)*trotx(-pi/2);
                            %                         T56 = trotz(q6)*transl(0,0,0)*trotx(0);

                            T06 = T01*T12*T23; %*T34*T45*T56;

                            i = i+1;

                            P = T06(1:3,4);
                            P1(i) = P(1);
                            P2(i) = P(2);
                            P3(i) = P(3);
                        end
                    end
                end
                %         end
                %     end
                % end

                plot3(ax1,P1,P2,P3,'b');


            elseif q6check ==0
                L(1) = Link([0 d1v a1v deg2rad(alp1)],'standard');
                L(2) = Link([0 d2v a2v deg2rad(alp2)],'standard');
                L(3) = Link([0 d3v a3v deg2rad(alp3)],'standard');
                L(4) = Link([0 d4v a4v deg2rad(alp4)],'standard');
                L(5) = Link([0 d5v a5v deg2rad(alp5)],'standard');

                %Link Movement Limits in terms of Joint angle variables
                L(1).qlim = [deg2rad(q1min) deg2rad(q1max)];
                L(2).qlim = [deg2rad(q2min) deg2rad(q2max)];
                L(3).qlim = [deg2rad(q3min) deg2rad(q3max)];
                L(4).qlim = [deg2rad(q4min) deg2rad(q4max)];
                L(5).qlim = [deg2rad(q5min) deg2rad(q5max)];

                % Rest Position
                q0 = [deg2rad(q1r) deg2rad(q2r) deg2rad(q3r) deg2rad(q4r) deg2rad(q5r)];
                %Final Position
                qf = [deg2rad(q1f) deg2rad(q2f) deg2rad(q3f) deg2rad(q4f) deg2rad(q5f)];

                %Build the Robot
                ROBO = SerialLink(L);
                ROBO.name = Roboname;
                ax1 = app.UIAxes;

                %Workspace
                i = 0;
                for q1 = deg2rad(q1min):deg2rad(10):deg2rad(q1max)
                    for q2 = deg2rad(q2min):deg2rad(10):deg2rad(q2max)
                        for q3 = deg2rad(q3min):deg2rad(10):deg2rad(q2max)
                            %             for q4 = deg2rad(-110):deg2rad(10):deg2rad(110)
                            %                 for q5 = deg2rad(-100):deg2rad(10):deg2rad(100)
                            %                     for q6 = deg2rad(-266):deg2rad(10):deg2rad(266)
                            T01 = trotz(q1)*transl(a1v,0,d1v)*trotx(deg2rad(alp1));
                            T12 = trotz(q2)*transl(a2v,0,d2v)*trotx(deg2rad(alp2));
                            T23 = trotz(q3)*transl(a3v,0,d3v)*trotx(deg2rad(alp3));
                            %                         T34 = trotz(q4)*transl(0,0,d4)*trotx(pi/2);
                            %                         T45 = trotz(q5)*transl(0,0,0)*trotx(-pi/2);
                            %                         T56 = trotz(q6)*transl(0,0,0)*trotx(0);

                            T06 = T01*T12*T23; %*T34*T45*T56;

                            i = i+1;

                            P = T06(1:3,4);
                            P1(i) = P(1);
                            P2(i) = P(2);
                            P3(i) = P(3);
                        end
                    end
                end
                %         end
                %     end
                % end

                plot3(ax1,P1,P2,P3,'b');
            end

        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            %Link Parameters INPUT
            tf = app.TimetoRunsecondsEditField.Value;
            x = app.FinalPositionEEX.Value; y = app.FinalPositionEEY.Value; z = app.FinalPositionEEZ.Value;

            q6check = app.theta6.Value;
            a1v = app.a1.Value;  a2v = app.a2.Value; a3v = app.a3.Value; a4v = app.a4.Value; a5v = app.a5.Value; a6v = app.a6.Value;
            d1v = app.d1.Value;  d2v = app.d2.Value; d3v = app.d3.Value; d4v = app.d4.Value; d5v = app.d5.Value; d6v = app.d6.Value;
            alp1 = app.alpha1.Value;alp2 = app.alpha2.Value;alp3 = app.alpha3.Value;alp4 = app.alpha4.Value;alp5 = app.alpha5.Value;alp6 = app.alpha6.Value;
            q1min = app.mintheta1.Value;q2min = app.mintheta2.Value;q3min = app.mintheta3.Value;q4min = app.mintheta4.Value;q5min = app.mintheta5.Value;q6min = app.mintheta6.Value;
            q1max = app.maxtheta1.Value;q2max = app.maxtheta2.Value;q3max = app.maxtheta3.Value;q4max = app.maxtheta4.Value;q5max = app.maxtheta5.Value;q6max = app.maxtheta6.Value;
            Robonameval = string(app.SelectaSerialRobotDropDown.Value);
            if Robonameval == string(1)
                Roboname = '6-DOF PUMA 560';
            elseif Robonameval == string(2)
                Roboname = '6-DOF ABB IRb-1400';
            elseif Robonameval == string(3)
                Roboname = '6-DOF STAUBLI RX160L';
            elseif Robonameval == string(4)
                Roboname = '5-DOF PIONEER-2';
            elseif Robonameval == string(5)
                Roboname = '5-DOF ASEA IRb-6';
            end

            app.StatusMessage_2.Value = 'Connecting to Matlab Mobile';
            clear m;
            list = mobiledevlist;
            put = list.('Device');
            m=mobiledev(put{1,1});
            sr = 100;
            m.SampleRate = sr;
            m.OrientationSensorEnabled = 1;
            app.StatusMessage_2.Value = 'Switching on the Orientation Sensor';
            ax2 = app.UIAxes2;
            scatter(ax2,0,0,'r','filled','o');
            sca8 = scatter(ax2,0,0,'r','filled','o');
            xline(ax2,0,'red');
            yline(ax2,0,'red');

            m.Logging=1;
            app.StatusMessage_2.Value = 'Acquiring Data';
            pause(1)
            tic;
            while (toc < tf) %toc command calculates the time elapsed from tic to get to toc.
                dtoc = round(toc);
                app.timerEditField.Value = (dtoc);
                [o,~] = orientlog(m);
                q1r = o(1,1);
                q2r = o(1,2);
                q3r = o(1,2);
                q4r = o(1,1);
                q5r = o(1,2);
                q6r = o(1,3);
                q1f = o(end,1);
                q2f = o(end,2);
                q3f = o(end,2);
                q4f = o(end,1);
                q5f = o(end,2);
                q6f = o(end,3);
                %DH Parameters
                %Standard or Classic
                if q6check ~= 0
                    L(1) = Link([0 d1v a1v deg2rad(alp1)],'standard');
                    L(2) = Link([0 d2v a2v deg2rad(alp2)],'standard');
                    L(3) = Link([0 d3v a3v deg2rad(alp3)],'standard');
                    L(4) = Link([0 d4v a4v deg2rad(alp4)],'standard');
                    L(5) = Link([0 d5v a5v deg2rad(alp5)],'standard');
                    L(6) = Link([0 d6v a6v deg2rad(alp6)],'standard');

                    %Link Movement Limits in terms of Joint angle variables
                    L(1).qlim = [deg2rad(q1min) deg2rad(q1max)];
                    L(2).qlim = [deg2rad(q2min) deg2rad(q2max)];
                    L(3).qlim = [deg2rad(q3min) deg2rad(q3max)];
                    L(4).qlim = [deg2rad(q4min) deg2rad(q4max)];
                    L(5).qlim = [deg2rad(q5min) deg2rad(q5max)];
                    L(6).qlim = [deg2rad(q6min) deg2rad(q6max)];

                    % Rest Position
                    q0 = [deg2rad(q1r) deg2rad(q2r) deg2rad(q3r) deg2rad(q4r) deg2rad(q5r) deg2rad(q6r)];
                    %Final Position
                    qf = [deg2rad(q1f) deg2rad(q2f) deg2rad(q3f) deg2rad(q4f) deg2rad(q5f) deg2rad(q6f)];

                    %Build the Robot
                    ROBO = SerialLink(L);
                    ROBO.name = Roboname;

                    tocpre = toc-1;
                    %Trajectory
                    t = tocpre:0.5:toc; %Time 0 to tf seconds in the increment of 0.15 INPUT
                    Q = jtraj(q0,qf,t); %Create Joint Angles from initial to final joint configuration
                    app.StatusMessage_2.Value = 'Running Simulation';
                    plot(ROBO,Q); drawnow;
                    delete(sca8);
                    sz = 200;
                    sca8 = scatter(ax2,o(end,1),o(end,2),sz,'r','filled','o'); drawnow;


                elseif q6check ==0
                    L(1) = Link([0 d1v a1v deg2rad(alp1)],'standard');
                    L(2) = Link([0 d2v a2v deg2rad(alp2)],'standard');
                    L(3) = Link([0 d3v a3v deg2rad(alp3)],'standard');
                    L(4) = Link([0 d4v a4v deg2rad(alp4)],'standard');
                    L(5) = Link([0 d5v a5v deg2rad(alp5)],'standard');

                    %Link Movement Limits in terms of Joint angle variables
                    L(1).qlim = [deg2rad(q1min) deg2rad(q1max)];
                    L(2).qlim = [deg2rad(q2min) deg2rad(q2max)];
                    L(3).qlim = [deg2rad(q3min) deg2rad(q3max)];
                    L(4).qlim = [deg2rad(q4min) deg2rad(q4max)];
                    L(5).qlim = [deg2rad(q5min) deg2rad(q5max)];

                    % Rest Position
                    q0 = [deg2rad(q1r) deg2rad(q2r) deg2rad(q3r) deg2rad(q4r) deg2rad(q5r)];
                    %Final Position
                    qf = [deg2rad(q1f) deg2rad(q2f) deg2rad(q3f) deg2rad(q4f) deg2rad(q5f)];

                    %Build the Robot
                    ROBO = SerialLink(L);
                    ROBO.name = Roboname;

                    tocpre = toc-1;
                    %Trajectory
                    t = tocpre:0.5:toc; %Time 0 to tf seconds in the increment of 0.15 INPUT
                    Q = jtraj(q0,qf,t); %Create Joint Angles from initial to final joint configuration
                    app.StatusMessage_2.Value = 'Running Simulation';
                    plot(ROBO,Q); drawnow;
                    delete(sca8);
                    sz = 200;
                    sca8 = scatter(ax2,o(end,1),o(end,2),sz,'r','filled','o'); drawnow;

                end
            end

            m.logging = 0;
        end

        % Button pushed function: StaticAnalysisButton
        function StaticAnalysisButtonPushed(app, event)
            app.UIAxes_2.reset
            app.UIAxes_2.Visible = 'off';
            %Link Parameters INPUT
            q1r = app.RestPositionq1.Value; q2r = app.RestPositionq2.Value; q3r = app.RestPositionq3.Value; q4r = app.RestPositionq4.Value; q5r = app.RestPositionq5.Value; q6r = app.RestPositionq6.Value;
            q1f = app.FinalPositionq1.Value; q2f = app.FinalPositionq2.Value; q3f = app.FinalPositionq3.Value; q4f = app.FinalPositionq4.Value; q5f = app.FinalPositionq5.Value; q6f = app.FinalPositionq6.Value;
            tf = app.finaltime.Value;
            x = app.FinalPositionEEX.Value; y = app.FinalPositionEEY.Value; z = app.FinalPositionEEZ.Value;
            fx = app.fxval.Value;  fy = app.fyval.Value;  fz = app.fzval.Value;
            nx = app.nxval.Value;  ny = app.nyval.Value;  nz = app.nzval.Value;

            q6check = app.theta6.Value;
            a1v = app.a1.Value;  a2v = app.a2.Value; a3v = app.a3.Value; a4v = app.a4.Value; a5v = app.a5.Value; a6v = app.a6.Value;
            d1v = app.d1.Value;  d2v = app.d2.Value; d3v = app.d3.Value; d4v = app.d4.Value; d5v = app.d5.Value; d6v = app.d6.Value;
            alp1 = app.alpha1.Value;alp2 = app.alpha2.Value;alp3 = app.alpha3.Value;alp4 = app.alpha4.Value;alp5 = app.alpha5.Value;alp6 = app.alpha6.Value;
            q1min = app.mintheta1.Value;q2min = app.mintheta2.Value;q3min = app.mintheta3.Value;q4min = app.mintheta4.Value;q5min = app.mintheta5.Value;q6min = app.mintheta6.Value;
            q1max = app.maxtheta1.Value;q2max = app.maxtheta2.Value;q3max = app.maxtheta3.Value;q4max = app.maxtheta4.Value;q5max = app.maxtheta5.Value;q6max = app.maxtheta6.Value;
            Robonameval = string(app.SelectaSerialRobotDropDown.Value);
            if Robonameval == string(1)
                Roboname = '6-DOF PUMA 560';
            elseif Robonameval == string(2)
                Roboname = '6-DOF ABB IRb-1400';
            elseif Robonameval == string(3)
                Roboname = '6-DOF STAUBLI RX160L';
            elseif Robonameval == string(4)
                Roboname = '5-DOF PIONEER-2';
            elseif Robonameval == string(5)
                Roboname = '5-DOF ASEA IRb-6';
            end

            %DH Parameters
            %Standard or Classic
            if q6check ~= 0
                L(1) = Link([0 d1v a1v deg2rad(alp1)],'standard');
                L(2) = Link([0 d2v a2v deg2rad(alp2)],'standard');
                L(3) = Link([0 d3v a3v deg2rad(alp3)],'standard');
                L(4) = Link([0 d4v a4v deg2rad(alp4)],'standard');
                L(5) = Link([0 d5v a5v deg2rad(alp5)],'standard');
                L(6) = Link([0 d6v a6v deg2rad(alp6)],'standard');

                %Link Movement Limits in terms of Joint angle variables
                L(1).qlim = [deg2rad(q1min) deg2rad(q1max)];
                L(2).qlim = [deg2rad(q2min) deg2rad(q2max)];
                L(3).qlim = [deg2rad(q3min) deg2rad(q3max)];
                L(4).qlim = [deg2rad(q4min) deg2rad(q4max)];
                L(5).qlim = [deg2rad(q5min) deg2rad(q5max)];
                L(6).qlim = [deg2rad(q6min) deg2rad(q6max)];

                % Rest Position
                q0 = [deg2rad(q1r) deg2rad(q2r) deg2rad(q3r) deg2rad(q4r) deg2rad(q5r) deg2rad(q6r)];
                %Final Position
                qf = [deg2rad(q1f) deg2rad(q2f) deg2rad(q3f) deg2rad(q4f) deg2rad(q5f) deg2rad(q6f)];

                %Build the Robot
                ROBO = SerialLink(L);
                ROBO.name = Roboname;
                ax1 = app.UIAxes;

                %Transformation Matrices & Rotation Matrices & Position Vectors
                T01 = trotz(deg2rad(q1f))*transl(a1v,0,d1v)*trotx(alp1);
                R01 = tr2rt(T01);
                O01 = transl(T01);
                T12 = trotz(deg2rad(q2f))*transl(a2v,0,d2v)*trotx(alp2);
                R12 = tr2rt(T12);
                O12 = transl(T12);
                T23 = trotz(deg2rad(q3f))*transl(a3v,0,d3v)*trotx(alp3);
                R23 = tr2rt(T23);
                O23 = transl(T23);
                T34 = trotz(deg2rad(q4f))*transl(a4v,0,d4v)*trotx(alp4);
                R34 = tr2rt(T34);
                O34 = transl(T34);
                T45 = trotz(deg2rad(q5f))*transl(a5v,0,d5v)*trotx(alp5);
                R45 = tr2rt(T45);
                O45 = transl(T45);
                T56 = trotz(deg2rad(q6f))*transl(a6v,0,d6v)*trotx(alp6);
                R56 = tr2rt(T56);
                O56 = transl(T56);

                R06 = R01*R12*R23*R34*R45*R56;

                %Force and Moment Acting on the End Effector w.r.t base {0} coordinate
                %system
                f06 = [fx;fy;fz];
                n06 = [nx;ny;nz];

                %Force and Moment acting on the endeffector in its own cooordinate system
                f66 = inv(R06)*f06;
                n66 = inv(R06)*n06;

                %Z Axis Vector
                Z = [0;0;1];

                %Force & Torque Vector Acting on each Joints
                f55 = R56*f66;
                f44 = R45*f55;
                f33 = R34*f44;
                f22 = R23*f33;
                f11 = R12*f22;
                f00 = R01*f11;

                n55 = R56*n66+cross(O56,f55);
                n44 = R45*n55+cross(O45,f44);
                n33 = R34*n44+cross(O34,f33);
                n22 = R23*n33+cross(O23,f22);
                n11 = R12*n22+cross(O12,f11);
                n00 = R01*n11+cross(O01,f00);

                %Torque to be applied at the joints to maintain the equilibrium
                tau5 = (n55.')*Z;
                tau4 = (n44.')*Z;
                tau3 = (n33.')*Z;
                tau2 = (n22.')*Z;
                tau1 = (n11.')*Z;
                tau0 = (n00.')*Z;

                app.torque1.Value = round(tau0,2);
                app.torque2.Value = round(tau1,2);
                app.torque3.Value = round(tau2,2);
                app.torque4.Value = round(tau3,2);
                app.torque5.Value = round(tau4,2);
                app.torque6.Value = round(tau5,2);

                %Jacobian Matrix
                Ja = jacob0(ROBO,qf);
                Jf = (jacob0(ROBO,qf)).';

                %Force-Torque Matrix
                FT = [f06;n06];
                tau = Jf*FT;


            elseif q6check ==0
                L(1) = Link([0 d1v a1v deg2rad(alp1)],'standard');
                L(2) = Link([0 d2v a2v deg2rad(alp2)],'standard');
                L(3) = Link([0 d3v a3v deg2rad(alp3)],'standard');
                L(4) = Link([0 d4v a4v deg2rad(alp4)],'standard');
                L(5) = Link([0 d5v a5v deg2rad(alp5)],'standard');

                %Link Movement Limits in terms of Joint angle variables
                L(1).qlim = [deg2rad(q1min) deg2rad(q1max)];
                L(2).qlim = [deg2rad(q2min) deg2rad(q2max)];
                L(3).qlim = [deg2rad(q3min) deg2rad(q3max)];
                L(4).qlim = [deg2rad(q4min) deg2rad(q4max)];
                L(5).qlim = [deg2rad(q5min) deg2rad(q5max)];

                % Rest Position
                q0 = [deg2rad(q1r) deg2rad(q2r) deg2rad(q3r) deg2rad(q4r) deg2rad(q5r)];
                %Final Position
                qf = [deg2rad(q1f) deg2rad(q2f) deg2rad(q3f) deg2rad(q4f) deg2rad(q5f)];

                %Build the Robot
                ROBO = SerialLink(L);
                ROBO.name = Roboname;
                ax1 = app.UIAxes;

                %Transformation Matrices & Rotation Matrices & Position Vectors
                T01 = trotz(deg2rad(q1f))*transl(a1v,0,d1v)*trotx(alp1);
                R01 = tr2rt(T01);
                O01 = transl(T01);
                T12 = trotz(deg2rad(q2f))*transl(a2v,0,d2v)*trotx(alp2);
                R12 = tr2rt(T12);
                O12 = transl(T12);
                T23 = trotz(deg2rad(q3f))*transl(a3v,0,d3v)*trotx(alp3);
                R23 = tr2rt(T23);
                O23 = transl(T23);
                T34 = trotz(deg2rad(q4f))*transl(a4v,0,d4v)*trotx(alp4);
                R34 = tr2rt(T34);
                O34 = transl(T34);
                T45 = trotz(deg2rad(q5f))*transl(a5v,0,d5v)*trotx(alp5);
                R45 = tr2rt(T45);
                O45 = transl(T45);


                R05 = R01*R12*R23*R34*R45;

                %Force and Moment Acting on the End Effector w.r.t base {0} coordinate
                %system
                f05 = [fx;fy;fz];
                n05 = [nx;ny;nz];

                %Force and Moment acting on the endeffector in its own cooordinate system
                f55 = inv(R05)*f05;
                n55 = inv(R05)*n05;

                %Z Axis Vector
                Z = [0;0;1];

                %Force & Torque Vector Acting on each Joints
                f44 = R45*f55;
                f33 = R34*f44;
                f22 = R23*f33;
                f11 = R12*f22;
                f00 = R01*f11;

                n44 = R45*n55+cross(O45,f44);
                n33 = R34*n44+cross(O34,f33);
                n22 = R23*n33+cross(O23,f22);
                n11 = R12*n22+cross(O12,f11);
                n00 = R01*n11+cross(O01,f00);

                %Torque to be applied at the joints to maintain the equilibrium
                tau4 = (n44.')*Z;
                tau3 = (n33.')*Z;
                tau2 = (n22.')*Z;
                tau1 = (n11.')*Z;
                tau0 = (n00.')*Z;

                app.torque1.Value = round(tau0,2);
                app.torque2.Value = round(tau1,2);
                app.torque3.Value = round(tau2,2);
                app.torque4.Value = round(tau3,2);
                app.torque5.Value = round(tau4,2);

                %Jacobian Matrix
                Ja = jacob0(ROBO,qf);
                Jf = (jacob0(ROBO,qf)).';

                %Force-Torque Matrix
                FT = [f05;n05];
                tau = Jf*FT;


            end
        end

        % Button pushed function: DynamicAnalysisButton
        function DynamicAnalysisButtonPushed(app, event)
            app.UIAxes_2.reset
            app.UIAxes_2.Visible = 'off';
            %Link Parameters INPUT
            q1r = app.RestPositionq1.Value; q2r = app.RestPositionq2.Value; q3r = app.RestPositionq3.Value; q4r = app.RestPositionq4.Value; q5r = app.RestPositionq5.Value; q6r = app.RestPositionq6.Value;
            q1f = app.FinalPositionq1.Value; q2f = app.FinalPositionq2.Value; q3f = app.FinalPositionq3.Value; q4f = app.FinalPositionq4.Value; q5f = app.FinalPositionq5.Value; q6f = app.FinalPositionq6.Value;
            tf = app.finaltime.Value;
            x = app.FinalPositionEEX.Value; y = app.FinalPositionEEY.Value; z = app.FinalPositionEEZ.Value;
            fx = app.fxval.Value;  fy = app.fyval.Value;  fz = app.fzval.Value;
            nx = app.nxval.Value;  ny = app.nyval.Value;  nz = app.nzval.Value;

            q6check = app.theta6.Value;
            a1v = app.a1.Value;  a2v = app.a2.Value; a3v = app.a3.Value; a4v = app.a4.Value; a5v = app.a5.Value; a6v = app.a6.Value;
            d1v = app.d1.Value;  d2v = app.d2.Value; d3v = app.d3.Value; d4v = app.d4.Value; d5v = app.d5.Value; d6v = app.d6.Value;
            alp1 = app.alpha1.Value;alp2 = app.alpha2.Value;alp3 = app.alpha3.Value;alp4 = app.alpha4.Value;alp5 = app.alpha5.Value;alp6 = app.alpha6.Value;
            q1min = app.mintheta1.Value;q2min = app.mintheta2.Value;q3min = app.mintheta3.Value;q4min = app.mintheta4.Value;q5min = app.mintheta5.Value;q6min = app.mintheta6.Value;
            q1max = app.maxtheta1.Value;q2max = app.maxtheta2.Value;q3max = app.maxtheta3.Value;q4max = app.maxtheta4.Value;q5max = app.maxtheta5.Value;q6max = app.maxtheta6.Value;
            Robonameval = string(app.SelectaSerialRobotDropDown.Value);
            if Robonameval == string(1)
                Roboname = '6-DOF PUMA 560';
            elseif Robonameval == string(2)
                Roboname = '6-DOF ABB IRb-1400';
            elseif Robonameval == string(3)
                Roboname = '6-DOF STAUBLI RX160L';
            elseif Robonameval == string(4)
                Roboname = '5-DOF PIONEER-2';
            elseif Robonameval == string(5)
                Roboname = '5-DOF ASEA IRb-6';
            end

            %DH Parameters
            %Standard or Classic
            if q6check ~= 0
                L(1) = Link([0 d1v a1v deg2rad(alp1)],'standard');
                L(2) = Link([0 d2v a2v deg2rad(alp2)],'standard');
                L(3) = Link([0 d3v a3v deg2rad(alp3)],'standard');
                L(4) = Link([0 d4v a4v deg2rad(alp4)],'standard');
                L(5) = Link([0 d5v a5v deg2rad(alp5)],'standard');
                L(6) = Link([0 d6v a6v deg2rad(alp6)],'standard');

                %Link Movement Limits in terms of Joint angle variables
                L(1).qlim = [deg2rad(q1min) deg2rad(q1max)];
                L(2).qlim = [deg2rad(q2min) deg2rad(q2max)];
                L(3).qlim = [deg2rad(q3min) deg2rad(q3max)];
                L(4).qlim = [deg2rad(q4min) deg2rad(q4max)];
                L(5).qlim = [deg2rad(q5min) deg2rad(q5max)];
                L(6).qlim = [deg2rad(q6min) deg2rad(q6max)];

                % Rest Position
                q0 = [deg2rad(q1r) deg2rad(q2r) deg2rad(q3r) deg2rad(q4r) deg2rad(q5r) deg2rad(q6r)];
                qi = [deg2rad(q1r) deg2rad(q2r) deg2rad(q3r) deg2rad(q4r) deg2rad(q5r) deg2rad(q6r)];
                %Final Position
                qf = [deg2rad(q1f) deg2rad(q2f) deg2rad(q3f) deg2rad(q4f) deg2rad(q5f) deg2rad(q6f)];

                %Build the Robot
                ROBO = SerialLink(L);
                ROBO.name = Roboname;
                ax1 = app.UIAxes;

                %Force and Moment Acting on the End Effector w.r.t base {0} coordinate
                %system
                f06 = [fx;fy;fz];
                n06 = [nx;ny;nz];

                %Z Axis Vector
                Z = [0;0;1];


                %Dynamic Analysis
                w00 = [0;0;0]; %Angular Velocity of Base is Zero
                v00 = [0;0;0]; %Linear Velocity of Base is Zero
                dv00 = [0;0;0]; %Linear Acceleration of Base is Zero
                dw00=[0;0;0]; %Angular Acceleration of Base is Zero

                g = [0;9.81;0]; %Gravity Vector
                m = 15; %Motor Mass
                M = 10; %Mass of Each Link

                %Inertial Tensor related to centre of mass of each link (Assumed to be zero
                %if we assume each link as a point mass
                IC11 = zeros(3);
                IC22 = zeros(3);
                IC33 = zeros(3);
                IC44 = zeros(3);
                IC55 = zeros(3);
                IC66 = zeros(3);

                %Cubic Trajectory(q = a*t^3+b*t^2+c*t^1+d)
                %qi and qf can also be found from inverse kinematics when position of end
                %effector is given(using Transformation matrix), as far as now qi=q0 and qf are already given readymade
                %The trajectory can be plotted using command in PUMA560_1_forw_inv_traj

                %Calculation of trajectory coefficitents(a,b,c,d)
                %Assume dq at initial time and final time t0, tf as zero
                tf = 5; %final time
                a = -2*(qf.'-qi.')/(tf^3);
                b = 3*(qf.'-qi.')/(tf^2);
                c = zeros(length(a),1);
                d = qi.';

                %NEWTON-EULER RECURSIVE FORMULATION
                k=0;
                for t=0:0.05:tf
                    k=k+1;
                    q = a*t^3+b*t^2+c*t^1+d;
                    dq = 3*a*t^2+2*b*t+c;
                    ddq = 6*a*t+2*b;

                    %Joint Variable
                    Q1 = q(1);
                    Q2 = q(2);
                    Q3 = q(3);
                    Q4 = q(4);
                    Q5 = q(5);
                    Q6 = q(6);

                    %Joint Velocity
                    dQ1 = dq(1);
                    dQ2 = dq(2);
                    dQ3 = dq(3);
                    dQ4 = dq(4);
                    dQ5 = dq(5);
                    dQ6 = dq(6);

                    %Joint Acceleration
                    ddQ1 = ddq(1);
                    ddQ2 = ddq(2);
                    ddQ3 = ddq(3);
                    ddQ4 = ddq(4);
                    ddQ5 = ddq(5);
                    ddQ6 = ddq(6);

                    %Transformation Matrices & Rotation Matrices & Position Vectors
                    T01 = trotz(Q1)*transl(a1v,0,d1v)*trotx(alp1);
                    R01 = tr2rt(T01);
                    R10 = R01.';
                    O01 = transl(T01);
                    T12 = trotz(Q2)*transl(a2v,0,d2v)*trotx(alp2);
                    R12 = tr2rt(T12);
                    R21 = R12.';
                    O12 = transl(T12);
                    T23 = trotz(Q3)*transl(a3v,0,d3v)*trotx(alp3);
                    R23 = tr2rt(T23);
                    R32 = R23.';
                    O23 = transl(T23);
                    T34 = trotz(Q4)*transl(a4v,0,d4v)*trotx(alp4);
                    R34 = tr2rt(T34);
                    R43 = R34.';
                    O34 = transl(T34);
                    T45 = trotz(Q5)*transl(a5v,0,d5v)*trotx(alp5);
                    R45 = tr2rt(T45);
                    R54 = R45.';
                    O45 = transl(T45);
                    T56 = trotz(Q6)*transl(a6v,0,d6v)*trotx(alp6);
                    R56 = tr2rt(T56);
                    R65 = R56.';
                    O56 = transl(T56);

                    R06 = R01*R12*R23*R34*R45*R56;
                    R60 = R10*R21*R32*R43*R54*R65;

                    %Force and Moment acting on the endeffector in its own cooordinate system
                    f66 = inv(R06)*f06;
                    n66 = inv(R06)*n06;


                    %Position of Centre of Mass of Each Link
                    PC11 = O01/2;
                    PC22 = O12/2;
                    PC33 = O23/2;
                    PC44 = O34/2;
                    PC55 = O45/2;
                    PC66 = O56/2;

                    %Recursive Newton-Euler Formulation
                    %Outward Iteration
                    %for link1, i = 0
                    w11 = R10*w00 + dQ1*Z;
                    dw11 = R10*dw00 + R10*cross(w00,(dQ1*Z))+ddQ1*Z;
                    v11 = R10*(v00+cross(w00,O01));
                    dv11 = R10*(cross(w00,O01)+cross(w00,cross(w00,O01))+dv00+g);
                    dvc1 = cross(dw11,PC11)+cross(w11,cross(w11,PC11))+dv11;
                    F11 = M*dvc1;
                    N11 = IC11*dw11+cross(w11,(IC11*w11));

                    %for link2, i = 1
                    w22 = R21*w11 + dQ2*Z;
                    dw22 = R21*dw11 + R21*cross(w11,(dQ2*Z))+ddQ2*Z;
                    v22 = R21*(v11+cross(w11,O12));
                    dv22 = R21*(cross(w11,O12)+cross(w11,cross(w11,O12))+dv11+g);
                    dvc2 = cross(dw22,PC22)+cross(w22,cross(w22,PC22))+dv22;
                    F22 = M*dvc2;
                    N22 = IC22*dw22+cross(w22,(IC22*w22));

                    %for link3, i = 2
                    w33 = R32*w22 + dQ3*Z;
                    dw33 = R32*dw22 + R32*cross(w22,(dQ3*Z))+ddQ3*Z;
                    v33 = R32*(v22+cross(w22,O23));
                    dv33 = R32*(cross(w22,O23)+cross(w22,cross(w22,O23))+dv22+g);
                    dvc3 = cross(dw33,PC33)+cross(w33,cross(w33,PC33))+dv33;
                    F33 = M*dvc3;
                    N33 = IC33*dw33+cross(w33,(IC33*w33));

                    %for link4, i = 3
                    w44 = R43*w33 + dQ4*Z;
                    dw44 = R43*dw33 + R43*cross(w33,(dQ4*Z))+ddQ4*Z;
                    v44 = R43*(v33+cross(w33,O34));
                    dv44 = R43*(cross(w33,O34)+cross(w33,cross(w33,O34))+dv33+g);
                    dvc4 = cross(dw44,PC44)+cross(w44,cross(w44,PC44))+dv44;
                    F44 = M*dv44;
                    N44 = IC44*dw44+cross(w44,(IC44*w44));

                    %for link5, i = 4
                    w55 = R54*w44 + dQ5*Z;
                    dw55 = R54*dw44 + R54*cross(w44,(dQ5*Z))+ddQ5*Z;
                    v55 = R54*(v44+cross(w44,O45));
                    dv55 = R54*(cross(w44,O45)+cross(w44,cross(w44,O45))+dv44+g);
                    dvc5 = cross(dw55,PC55)+cross(w55,cross(w55,PC55))+dv55;
                    F55 = M*dv55;
                    N55 = IC55*dw55+cross(w55,(IC55*w55));

                    %for link6, i = 5
                    w66 = R65*w55 + dQ6*Z;
                    dw66 = R65*dw55 + R65*cross(w55,(dQ6*Z))+ddQ6*Z;
                    v66 = R65*(v55+cross(w55,O56));
                    dv66 = R65*(cross(w55,O56)+cross(w55,cross(w55,O56))+dv55+g);
                    dvc6 = cross(dw66,PC66)+cross(w66,cross(w66,PC66))+dv66;
                    F66 = M*dv66;
                    N66 = IC66*dw66+cross(w66,(IC66*w66));


                    %Inward Iteration
                    %for link6, i =5
                    f55 = R56*f66+F66;
                    n55 = N66+R56*n66+cross(PC66,F66)+cross(O56,(R56*f66));
                    tau55 = n55.'*Z;

                    %for link5, i =4
                    f44 = R45*f55+F55;
                    n44 = N55+R45*n55+cross(PC55,F55)+cross(O45,(R45*f55));
                    tau44 = n44.'*Z;

                    %for link4, i =3
                    f33 = R34*f44+F44;
                    n33 = N44+R34*n44+cross(PC44,F44)+cross(O34,(R34*f44));
                    tau33 = n33.'*Z;

                    %for link3, i =2
                    f22 = R23*f33+F33;
                    n22 = N33+R23*n33+cross(PC33,F33)+cross(O23,(R23*f33));
                    tau22 = n22.'*Z;

                    %for link2, i =1
                    f11 = R12*f22+F22;
                    n11 = N22+R12*n22+cross(PC22,F22)+cross(O12,(R12*f22));
                    tau11 = n11.'*Z;

                    %for link1, i =0
                    f00 = R01*f11+F11;
                    n00 = N11+R01*n11+cross(PC11,F11)+cross(O01,(R01*f11));
                    tau00 = n00.'*Z;

                    %Joint TORQUES
                    TAU1(k) = tau00;
                    TAU2(k) = tau11;
                    TAU3(k) = tau22;
                    TAU4(k) = tau33;
                    TAU5(k) = tau44;
                    TAU6(k) = tau55;
                end
                cla(ax1); drawnow;
                t = 0:0.05:tf;
                ax22 = app.UIAxes_2;
                app.UIAxes.Visible = 'off'; drawnow
                app.UIAxes_2.Visible = 'on'; drawnow;
                plot(ax22,t,TAU1,'r',t,TAU2,'b',t,TAU3,'g',t,TAU4,'y',t,TAU5,'c',t,TAU6,'m');
                xlabel(ax22,'time (seconds)');
                ylabel(ax22,'Torque(N.m)');
                legend(ax22,'\tau1','\tau2','\tau3','\tau4','\tau5','\tau6');


            elseif q6check ==0
                L(1) = Link([0 d1v a1v deg2rad(alp1)],'standard');
                L(2) = Link([0 d2v a2v deg2rad(alp2)],'standard');
                L(3) = Link([0 d3v a3v deg2rad(alp3)],'standard');
                L(4) = Link([0 d4v a4v deg2rad(alp4)],'standard');
                L(5) = Link([0 d5v a5v deg2rad(alp5)],'standard');

                %Link Movement Limits in terms of Joint angle variables
                L(1).qlim = [deg2rad(q1min) deg2rad(q1max)];
                L(2).qlim = [deg2rad(q2min) deg2rad(q2max)];
                L(3).qlim = [deg2rad(q3min) deg2rad(q3max)];
                L(4).qlim = [deg2rad(q4min) deg2rad(q4max)];
                L(5).qlim = [deg2rad(q5min) deg2rad(q5max)];

                % Rest Position
                q0 = [deg2rad(q1r) deg2rad(q2r) deg2rad(q3r) deg2rad(q4r) deg2rad(q5r)];
                %Final Position
                qf = [deg2rad(q1f) deg2rad(q2f) deg2rad(q3f) deg2rad(q4f) deg2rad(q5f)];

                %Build the Robot
                ROBO = SerialLink(L);
                ROBO.name = Roboname;
                ax1 = app.UIAxes;

                %Force and Moment Acting on the End Effector w.r.t base {0} coordinate
                %system
                f05 = [fx;fy;fz];
                n05 = [nx;ny;nz];

                %Z Axis Vector
                Z = [0;0;1];

                %Dynamic Analysis
                w00 = [0;0;0]; %Angular Velocity of Base is Zero
                v00 = [0;0;0]; %Linear Velocity of Base is Zero
                dv00 = [0;0;0]; %Linear Acceleration of Base is Zero
                dw00=[0;0;0]; %Angular Acceleration of Base is Zero

                g = [0;9.81;0]; %Gravity Vector
                m = 15; %Motor Mass
                M = 10; %Mass of Each Link

                %Inertial Tensor related to centre of mass of each link (Assumed to be zero
                %if we assume each link as a point mass
                IC11 = zeros(3);
                IC22 = zeros(3);
                IC33 = zeros(3);
                IC44 = zeros(3);
                IC55 = zeros(3);

                %Cubic Trajectory(q = a*t^3+b*t^2+c*t^1+d)
                %qi and qf can also be found from inverse kinematics when position of end
                %effector is given(using Transformation matrix), as far as now qi=q0 and qf are already given readymade
                %The trajectory can be plotted using command in PUMA560_1_forw_inv_traj

                %Calculation of trajectory coefficitents(a,b,c,d)
                %Assume dq at initial time and final time t0, tf as zero
                tf = 5; %final time
                a = -2*(qf.'-qi.')/(tf^3);
                b = 3*(qf.'-qi.')/(tf^2);
                c = zeros(length(a),1);
                d = qi.';

                %NEWTON-EULER RECURSIVE FORMULATION
                k=0;
                for t=0:0.05:tf
                    k=k+1;
                    q = a*t^3+b*t^2+c*t^1+d;
                    dq = 3*a*t^2+2*b*t+c;
                    ddq = 6*a*t+2*b;

                    %Joint Variable
                    Q1 = q(1);
                    Q2 = q(2);
                    Q3 = q(3);
                    Q4 = q(4);
                    Q5 = q(5);

                    %Joint Velocity
                    dQ1 = dq(1);
                    dQ2 = dq(2);
                    dQ3 = dq(3);
                    dQ4 = dq(4);
                    dQ5 = dq(5);

                    %Joint Acceleration
                    ddQ1 = ddq(1);
                    ddQ2 = ddq(2);
                    ddQ3 = ddq(3);
                    ddQ4 = ddq(4);
                    ddQ5 = ddq(5);

                    %Transformation Matrices & Rotation Matrices & Position Vectors
                    T01 = trotz(Q1)*transl(a1v,0,d1v)*trotx(alp1);
                    R01 = tr2rt(T01);
                    R10 = R01.';
                    O01 = transl(T01);
                    T12 = trotz(Q2)*transl(a2v,0,d2v)*trotx(alp2);
                    R12 = tr2rt(T12);
                    R21 = R12.';
                    O12 = transl(T12);
                    T23 = trotz(Q3)*transl(a3v,0,d3v)*trotx(alp3);
                    R23 = tr2rt(T23);
                    R32 = R23.';
                    O23 = transl(T23);
                    T34 = trotz(Q4)*transl(a4v,0,d4v)*trotx(alp4);
                    R34 = tr2rt(T34);
                    R43 = R34.';
                    O34 = transl(T34);
                    T45 = trotz(Q5)*transl(a5v,0,d5v)*trotx(alp5);
                    R45 = tr2rt(T45);
                    R54 = R45.';
                    O45 = transl(T45);


                    R06 = R01*R12*R23*R34*R45;
                    R60 = R10*R21*R32*R43*R54;

                    %Force and Moment acting on the endeffector in its own cooordinate system
                    f55 = inv(R06)*f05;
                    n55 = inv(R06)*n05;


                    %Position of Centre of Mass of Each Link
                    PC11 = O01/2;
                    PC22 = O12/2;
                    PC33 = O23/2;
                    PC44 = O34/2;
                    PC55 = O45/2;

                    %Recursive Newton-Euler Formulation
                    %Outward Iteration
                    %for link1, i = 0
                    w11 = R10*w00 + dQ1*Z;
                    dw11 = R10*dw00 + R10*cross(w00,(dQ1*Z))+ddQ1*Z;
                    v11 = R10*(v00+cross(w00,O01));
                    dv11 = R10*(cross(w00,O01)+cross(w00,cross(w00,O01))+dv00+g);
                    dvc1 = cross(dw11,PC11)+cross(w11,cross(w11,PC11))+dv11;
                    F11 = M*dvc1;
                    N11 = IC11*dw11+cross(w11,(IC11*w11));

                    %for link2, i = 1
                    w22 = R21*w11 + dQ2*Z;
                    dw22 = R21*dw11 + R21*cross(w11,(dQ2*Z))+ddQ2*Z;
                    v22 = R21*(v11+cross(w11,O12));
                    dv22 = R21*(cross(w11,O12)+cross(w11,cross(w11,O12))+dv11+g);
                    dvc2 = cross(dw22,PC22)+cross(w22,cross(w22,PC22))+dv22;
                    F22 = M*dvc2;
                    N22 = IC22*dw22+cross(w22,(IC22*w22));

                    %for link3, i = 2
                    w33 = R32*w22 + dQ3*Z;
                    dw33 = R32*dw22 + R32*cross(w22,(dQ3*Z))+ddQ3*Z;
                    v33 = R32*(v22+cross(w22,O23));
                    dv33 = R32*(cross(w22,O23)+cross(w22,cross(w22,O23))+dv22+g);
                    dvc3 = cross(dw33,PC33)+cross(w33,cross(w33,PC33))+dv33;
                    F33 = M*dvc3;
                    N33 = IC33*dw33+cross(w33,(IC33*w33));

                    %for link4, i = 3
                    w44 = R43*w33 + dQ4*Z;
                    dw44 = R43*dw33 + R43*cross(w33,(dQ4*Z))+ddQ4*Z;
                    v44 = R43*(v33+cross(w33,O34));
                    dv44 = R43*(cross(w33,O34)+cross(w33,cross(w33,O34))+dv33+g);
                    dvc4 = cross(dw44,PC44)+cross(w44,cross(w44,PC44))+dv44;
                    F44 = M*dv44;
                    N44 = IC44*dw44+cross(w44,(IC44*w44));

                    %for link5, i = 4
                    w55 = R54*w44 + dQ5*Z;
                    dw55 = R54*dw44 + R54*cross(w44,(dQ5*Z))+ddQ5*Z;
                    v55 = R54*(v44+cross(w44,O45));
                    dv55 = R54*(cross(w44,O45)+cross(w44,cross(w44,O45))+dv44+g);
                    dvc5 = cross(dw55,PC55)+cross(w55,cross(w55,PC55))+dv55;
                    F55 = M*dv55;
                    N55 = IC55*dw55+cross(w55,(IC55*w55));


                    %Inward Iteration

                    %for link5, i =4
                    f44 = R45*f55+F55;
                    n44 = N55+R45*n55+cross(PC55,F55)+cross(O45,(R45*f55));
                    tau44 = n44.'*Z;

                    %for link4, i =3
                    f33 = R34*f44+F44;
                    n33 = N44+R34*n44+cross(PC44,F44)+cross(O34,(R34*f44));
                    tau33 = n33.'*Z;

                    %for link3, i =2
                    f22 = R23*f33+F33;
                    n22 = N33+R23*n33+cross(PC33,F33)+cross(O23,(R23*f33));
                    tau22 = n22.'*Z;

                    %for link2, i =1
                    f11 = R12*f22+F22;
                    n11 = N22+R12*n22+cross(PC22,F22)+cross(O12,(R12*f22));
                    tau11 = n11.'*Z;

                    %for link1, i =0
                    f00 = R01*f11+F11;
                    n00 = N11+R01*n11+cross(PC11,F11)+cross(O01,(R01*f11));
                    tau00 = n00.'*Z;

                    %Joint TORQUES
                    TAU1(k) = tau00;
                    TAU2(k) = tau11;
                    TAU3(k) = tau22;
                    TAU4(k) = tau33;
                    TAU5(k) = tau44;
                end
                cla(ax1); drawnow;
                t = 0:0.05:tf;
                ax22 = app.UIAxes_2;
                app.UIAxes.Visible = 'off'; drawnow
                app.UIAxes_2.Visible = 'on'; drawnow;
                plot(ax22,t,TAU1,'r',t,TAU2,'b',t,TAU3,'g',t,TAU4,'y',t,TAU5,'m');
                xlabel(ax22,'time (seconds)');
                ylabel(ax22,'Torque(N.m)');
                legend(ax22,'\tau1','\tau2','\tau3','\tau4','\tau5');

            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.Image.Visible = 'on';
            app.Image_2.Visible = 'on';

            function [x, y, z, bdNodes] = cad2pc(filename)
                if nargin < 1
                    error('Too few input arguments')
                end
                if nargout > 5
                    error('Too many output arguments')
                end
                fid=fopen(filename);
                if fid == -1
                    error('File could not be opened, pls check the path.')
                end
                disp('>>>cad_to_pointcloud_conversion.[START]')
                %tic;toc;
                nline = 0;
                binary = -1;
                ascii = -1;
                binaryOrText = -1;
                stlLines={};
                while ~feof(fid)
                    tline = fgetl(fid);
                    nline = nline + 1;
                    if contains(tline, 'endfacet')
                        ascii = 1;
                        binary = 0;
                    end
                    if nline >= 50
                        break;
                    end
                end

                if ascii == 1 && binary ==0
                    disp('FileMode: text');
                    binaryOrText = 0;
                else
                    disp('FileMode: binary');
                    binaryOrText = 1;
                end

                num_facet = 0;
                frewind(fid)
                switch binaryOrText
                    case 0
                        while ~feof(fid)
                            stlLines=[stlLines;fgets(fid)];
                        end

                        %norm = [];
                        ver1=[];ver2=[];ver3=[];
                        x=[];y=[];z=[];
                        nmRow=[];
                        %x=zeros(3,num_facet); y=zeros(3,num_facet); z=zeros(3,num_facet);
                        for i = 1:size(stlLines,1)
                            %tline = fgetl(fid);
                            tl = stlLines{i};
                            id = strfind(tl,'facet normal');
                            if ~isempty(id)
                                %if contains(tl,'facet normal')
                                nmRow=[nmRow;i];
                                num_facet = num_facet+1;
                                %tk = tl(id+13:end); %15 or id+13(better&more stable)
                                %nm = strsplit(tk,' ');
                                %norm = [norm; str2double(nm)]; %% skip

                                if i+2 <= size(stlLines,1) && i+3 <= size(stlLines,1) && i+4 <= size(stlLines,1)
                                    ver1Str = stlLines{i+2};
                                    ver2Str = stlLines{i+3};
                                    ver3Str = stlLines{i+4};
                                    id1 = strfind(ver1Str,'vertex');
                                    id2 = strfind(ver2Str,'vertex');
                                    id3 = strfind(ver3Str,'vertex');
                                    if isempty(id1) || isempty(id2) || isempty(id3)
                                        %if ~contains(ver1Str,'vertex')||~contains(ver2Str,'vertex')||~contains(ver3Str,'vertex')
                                        error('Exception0x0815 of vertex.')
                                    end
                                    tk1 = ver1Str(id1+7:end);  %11 or id1+7(better&more stable)
                                    tk2 = ver2Str(id2+7:end);
                                    tk3 = ver3Str(id3+7:end);
                                    nm1 = strsplit(tk1,' ');
                                    nm2 = strsplit(tk2,' ');
                                    nm3 = strsplit(tk3,' ');
                                    ver1 = [ver1; str2double(nm1)];
                                    ver2 = [ver2; str2double(nm2)];
                                    ver3 = [ver3; str2double(nm3)];
                                    x=[x;ver1(end,1) ver2(end,1) ver3(end,1)];  %% For patch validation
                                    y=[y;ver1(end,2) ver2(end,2) ver3(end,2)];
                                    z=[z;ver1(end,3) ver2(end,3) ver3(end,3)];
                                end
                            end
                        end
                        x=x';y=y';z=z';

                    case 1

                        ftitle=fread(fid,80,'uchar=>schar'); % Read file title
                        num_facet=fread(fid,1,'int32'); % Read number of Facets

                        fprintf('\nTitle: %s\n', char(ftitle'));
                        fprintf('Num Facets: %d\n', num_facet);
                        x=zeros(3,num_facet); y=zeros(3,num_facet); z=zeros(3,num_facet);
                        ver1=zeros(num_facet,3); ver2=zeros(num_facet,3); ver3=zeros(num_facet,3);
                        for i=1:num_facet
                            norm=fread(fid,3,'float32'); % normal coordinates, ignored for now
                            ver1(i,:)=fread(fid,3,'float32'); % vertex 1
                            ver2(i,:)=fread(fid,3,'float32'); % vertex 2
                            ver3(i,:)=fread(fid,3,'float32'); % vertex 3
                            col=fread(fid,1,'uint16'); % color bytes

                            x(:,i)=[ver1(i,1); ver2(i,1); ver3(i,1)]; % convert to matlab "patch" compatible format
                            y(:,i)=[ver1(i,2); ver2(i,2); ver3(i,2)];
                            z(:,i)=[ver1(i,3); ver2(i,3); ver3(i,3)];
                        end

                end
                fclose(fid);

                bdVertexes=[ver1;ver2;ver3];
                bdNodes=unique(bdVertexes, 'rows');

                %% The following interior-node module
                %imSize = [512 512 300];
                %imSpacing = [1 1 1]; % [.5 0.5 0.5]

                minX_bd = min(bdNodes(:,1));
                maxX_bd = max(bdNodes(:,1));
                minY_bd = min(bdNodes(:,2));
                maxY_bd = max(bdNodes(:,2));
                minZ_bd = min(bdNodes(:,3));
                maxZ_bd = max(bdNodes(:,3));

                disp('Default dimSize below.')
                dimSize = [ceil(maxX_bd) - floor(minX_bd), ceil(maxY_bd) - floor(minY_bd), ceil(maxZ_bd) - floor(minZ_bd)]  %imSize
                imSpacing = (1/5)*dimSize;
                for i = 1 : length(imSpacing)
                    if imSpacing(i)>1
                        imSpacing(i) = 3;
                    end
                end
                imSpacing;
                origin = [0. 0. 0.];
                % posCrd = origin + idxCrd*imSpacing, for medical image computing;
                % if using posCrd, stepSize h(i)=imSpacing(i); rangeSz(i) = imSize(i)*imSpacing(i);
                inNodesPre=[];
                inNodes=[];
                for i= floor(minX_bd):1:ceil(maxX_bd)
                    for j= floor(minY_bd):1:ceil(maxY_bd)
                        for k= floor(minZ_bd):1:ceil(maxZ_bd)
                            tmpX = origin(1) + i * imSpacing(1);
                            tmpY = origin(2) + j * imSpacing(2);
                            tmpZ = origin(3) + k * imSpacing(3);
                            if tmpX >= minX_bd && tmpX <= maxX_bd && ...
                                    tmpY >= minY_bd && tmpY <= maxY_bd && ...
                                    tmpZ >= minZ_bd && tmpZ <= maxZ_bd
                                inNodesPre = [inNodesPre; tmpX tmpY tmpZ];
                            end
                        end
                    end
                end

                if isempty(inNodesPre)
                    fprintf('\n!ERROR!Warning:The dimSize or imSpacing may be not suitable.\n')
                end

                %% fix x, evaluate y,z. (pnpoly method)
                for i= 1:1:size(inNodesPre,1)
                    polyEdge2dId = find((bdNodes(:,1)>= inNodesPre(i, 1)-.5)&(bdNodes(:,1)<= inNodesPre(i, 1)+.4));
                    vertY = bdNodes(polyEdge2dId,2);
                    vertZ = bdNodes(polyEdge2dId,3);
                    testY = inNodesPre(i,2);
                    testZ = inNodesPre(i,3);
                    c = 0;

                    for k=1:1:length(vertY)
                        if k == 1
                            j = length(vertY);
                        else
                            j = k - 1;
                        end
                        if ( ((vertZ(k)>testZ) ~= (vertZ(j)>testZ)) && ...
                                (testY < (vertY(j)-vertY(k)) * (testZ-vertZ(k)) / (vertZ(j)-vertZ(k)) + vertY(k)) )
                            c = ~c;
                        end
                    end
                    if ~mod(c,2)
                        %disp('even -- outside')
                    else
                        %disp('uneven -- inside')
                        inNodes = [inNodes; inNodesPre(i,:)];
                    end
                end

                if isempty(inNodes)
                    fprintf('\n!ERROR!Warning:The dimSize or imSpacing, or just the STL file may be not suitable for computing interior nodes.\n')
                end

                disp('>>>cad_2_pointcloud_conversion.[OVER]')
            end


            ax3 = app.UIAxes3;
            ax33 = app.UIAxes3_3;
            %             figure;
            [x, y, z, bdNodes] = cad2pc('D:\Sync Drives\OneDrive - Indian Institute of Science\IISc PhD 2020\Course Work\2.Even 20-21\ME246 Robotics\Homework\Term Project\Robotics Project\My Robotics Project\Part1.stl');
            scatter3(ax3,bdNodes(:,1), bdNodes(:,2), bdNodes(:,3), 'MarkerFaceColor','y'); drawnow;
            [x1, y1, z1, bdNodes1] = cad2pc('D:\Sync Drives\OneDrive - Indian Institute of Science\IISc PhD 2020\Course Work\2.Even 20-21\ME246 Robotics\Homework\Term Project\Robotics Project\My Robotics Project\Part1_finish.stl');
            scatter3(ax33,bdNodes1(:,1), bdNodes1(:,2), bdNodes1(:,3), 'MarkerFaceColor','m'); drawnow;


            %
            %             %Link Parameters INPUT
            %             q1r = app.RestPositionq1.Value; q2r = app.RestPositionq2.Value; q3r = app.RestPositionq3.Value; q4r = app.RestPositionq4.Value; q5r = app.RestPositionq5.Value; q6r = app.RestPositionq6.Value;
            %             q1f = app.FinalPositionq1.Value; q2f = app.FinalPositionq2.Value; q3f = app.FinalPositionq3.Value; q4f = app.FinalPositionq4.Value; q5f = app.FinalPositionq5.Value; q6f = app.FinalPositionq6.Value;
            %             tf = app.finaltime.Value;
            %             x = app.FinalPositionEEX.Value; y = app.FinalPositionEEY.Value; z = app.FinalPositionEEZ.Value;
            %
            %             q6check = app.theta6.Value;
            %             a1v = app.a1.Value;  a2v = app.a2.Value; a3v = app.a3.Value; a4v = app.a4.Value; a5v = app.a5.Value; a6v = app.a6.Value;
            %             d1v = app.d1.Value;  d2v = app.d2.Value; d3v = app.d3.Value; d4v = app.d4.Value; d5v = app.d5.Value; d6v = app.d6.Value;
            %             alp1 = app.alpha1.Value;alp2 = app.alpha2.Value;alp3 = app.alpha3.Value;alp4 = app.alpha4.Value;alp5 = app.alpha5.Value;alp6 = app.alpha6.Value;
            %             q1min = app.mintheta1.Value;q2min = app.mintheta2.Value;q3min = app.mintheta3.Value;q4min = app.mintheta4.Value;q5min = app.mintheta5.Value;q6min = app.mintheta6.Value;
            %             q1max = app.maxtheta1.Value;q2max = app.maxtheta2.Value;q3max = app.maxtheta3.Value;q4max = app.maxtheta4.Value;q5max = app.maxtheta5.Value;q6max = app.maxtheta6.Value;
            %             Robonameval = string(app.SelectaSerialRobotDropDown.Value);
            %             if Robonameval == string(1)
            %                 Roboname = '6-DOF PUMA 560';
            %             elseif Robonameval == string(2)
            %                 Roboname = '6-DOF ABB IRb-1400';
            %             elseif Robonameval == string(3)
            %                 Roboname = '6-DOF STAUBLI RX160L';
            %             elseif Robonameval == string(4)
            %                 Roboname = '5-DOF PIONEER-2';
            %             elseif Robonameval == string(5)
            %                 Roboname = '5-DOF ASEA IRb-6';
            %             end
            %
            %             %DH Parameters
            %             %Standard or Classic
            %             if q6check ~= 0
            %                 L(1) = Link([0 d1v a1v deg2rad(alp1)],'standard');
            %                 L(2) = Link([0 d2v a2v deg2rad(alp2)],'standard');
            %                 L(3) = Link([0 d3v a3v deg2rad(alp3)],'standard');
            %                 L(4) = Link([0 d4v a4v deg2rad(alp4)],'standard');
            %                 L(5) = Link([0 d5v a5v deg2rad(alp5)],'standard');
            %                 L(6) = Link([0 d6v a6v deg2rad(alp6)],'standard');
            %
            %                 %Link Movement Limits in terms of Joint angle variables
            %                 L(1).qlim = [deg2rad(q1min) deg2rad(q1max)];
            %                 L(2).qlim = [deg2rad(q2min) deg2rad(q2max)];
            %                 L(3).qlim = [deg2rad(q3min) deg2rad(q3max)];
            %                 L(4).qlim = [deg2rad(q4min) deg2rad(q4max)];
            %                 L(5).qlim = [deg2rad(q5min) deg2rad(q5max)];
            %                 L(6).qlim = [deg2rad(q6min) deg2rad(q6max)];
            %
            %                 % Rest Position
            %                 q0 = [deg2rad(q1r) deg2rad(q2r) deg2rad(q3r) deg2rad(q4r) deg2rad(q5r) deg2rad(q6r)];
            %                 %Final Position
            %                 qf = [-1600 deg2rad(q2f) deg2rad(q3f) deg2rad(q4f) deg2rad(q5f) deg2rad(q6f)];
            %
            %                 %Build the Robot
            %                 ROBO = SerialLink(L);
            %                 ROBO.name = Roboname;
            %                 ax1 = app.UIAxes;
            %
            %                 % Forward Kinematics
            %                 Tf = ROBO.fkine(qf); %Transformation matrix from Forward Kinematics
            %
            %                 %Trajectory
            %                 t = 0:0.15:25; %Time 0 to tf seconds in the increment of 0.15 INPUT
            %                 Q = jtraj(q0,qf,t); %Create Joint Angles from initial to final joint configuration
            %                 Tr = ROBO.fkine(Q);
            %
            %                 %To show the trajectory of the robot
            %                 for i = 1:1:length(t)
            %                     T = Tr(i);
            %                     trs = transl(T); %Takes the last column of Translation Matrix of T
            %                     xx(i) = trs(1);
            %                     yy(i) = trs(2);
            %                     zz(i) = trs(3);
            %                 end
            % %                 plot3(xx,yy,zz,'color',[1 0 0],'LineWidth',2);
            % %                 hold on;
            % %                 plot(ROBO,Q); hold off;
            %
            %
            %
            %             elseif q6check ==0
            %                 L(1) = Link([0 d1v a1v deg2rad(alp1)],'standard');
            %                 L(2) = Link([0 d2v a2v deg2rad(alp2)],'standard');
            %                 L(3) = Link([0 d3v a3v deg2rad(alp3)],'standard');
            %                 L(4) = Link([0 d4v a4v deg2rad(alp4)],'standard');
            %                 L(5) = Link([0 d5v a5v deg2rad(alp5)],'standard');
            %
            %                 %Link Movement Limits in terms of Joint angle variables
            %                 L(1).qlim = [deg2rad(q1min) deg2rad(q1max)];
            %                 L(2).qlim = [deg2rad(q2min) deg2rad(q2max)];
            %                 L(3).qlim = [deg2rad(q3min) deg2rad(q3max)];
            %                 L(4).qlim = [deg2rad(q4min) deg2rad(q4max)];
            %                 L(5).qlim = [deg2rad(q5min) deg2rad(q5max)];
            %
            %                 % Rest Position
            %                 q0 = [deg2rad(q1r) deg2rad(q2r) deg2rad(q3r) deg2rad(q4r) deg2rad(q5r)];
            %                 %Final Position
            %                 qf = [deg2rad(q1f) deg2rad(q2f) deg2rad(q3f) deg2rad(q4f) deg2rad(q5f)];
            %
            %                 %Build the Robot
            %                 ROBO = SerialLink(L);
            %                 ROBO.name = Roboname;
            %                 ax1 = app.UIAxes;
            %
            %                 % Forward Kinematics
            %                 Tf = ROBO.fkine(qf); %Transformation matrix from Forward Kinematics
            %
            %                 %Trajectory
            %                 t = 0:0.15:25; %Time 0 to tf seconds in the increment of 0.15 INPUT
            %                 Q = jtraj(q0,qf,t); %Create Joint Angles from initial to final joint configuration
            %                 Tr = ROBO.fkine(Q);
            %
            %                 %To show the trajectory of the robot
            %                 for i = 1:1:length(t)
            %                     T = Tr(i);
            %                     trs = transl(T); %Takes the last column of Translation Matrix of T
            %                     xx(i) = trs(1);
            %                     yy(i) = trs(2);
            %                     zz(i) = trs(3);
            %                 end
            % %                 plot3(xx,yy,zz,'color',[1 0 0],'LineWidth',2);
            % %                 hold on;
            % %                 plot(ROBO,Q); hold off;
            %
            %             end
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            workspace;  % Make sure the workspace panel is showing.
            format long g;
            format compact;
            fontSize = 36;
            % Check that user has the Image Processing Toolbox installed.
            hasIPT = license('test', 'image_toolbox');
            if ~hasIPT
                % User does not have the toolbox installed.
                message = sprintf('Sorry, but you do not seem to have the Image Processing Toolbox.\nDo you want to try to continue anyway?');
                reply = questdlg(message, 'Toolbox missing', 'Yes', 'No', 'Yes');
                if strcmpi(reply, 'No')
                    % User said No, so exit.
                    return;
                end
            end
            %===============================================================================
            % Read in a standard MATLAB color demo image.
            folder = 'D:\Sync Drives\OneDrive - Indian Institute of Science\IISc PhD 2020\Course Work\2.Even 20-21\ME246 Robotics\Homework\Term Project\Robotics Project\My Robotics Project';
            baseFileName = 'part1 - Copy.jpg';
            % Get the full filename, with path prepended.
            fullFileName = fullfile(folder, baseFileName);
            if ~exist(fullFileName, 'file')
                % Didn't find it there.  Check the search path for it.
                fullFileName = baseFileName; % No path this time.
                if ~exist(fullFileName, 'file')
                    % Still didn't find it.  Alert user.
                    errorMessage = sprintf('Error: %s does not exist.', fullFileName);
                    uiwait(warndlg(errorMessage));
                    return;
                end
            end
            rgbImage = imread(fullFileName);
            % Get the dimensions of the image.  numberOfColorBands should be = 3.
            [rows, columns, numberOfColorBands] = size(rgbImage);
            % Display the original color image.
            subplot(2, 2, 1);
            imshow(rgbImage);
            axis on;
            title('Original Color Image', 'FontSize', fontSize);
            % Enlarge figure to full screen.
            set(gcf, 'Units', 'Normalized', 'Outerposition', [0, 0, 1, 1]);
            % Extract the individual red, green, and blue color channels.
            % redChannel = rgbImage(:, :, 1);
            greenChannel = rgbImage(:, :, 2);
            % blueChannel = rgbImage(:, :, 3);
            % Get the binaryImage
            binaryImage = greenChannel < 200;
            % Display the original color image.
            subplot(2, 2, 2);
            imshow(binaryImage);
            axis on;
            title('Binary Image', 'FontSize', fontSize);
            % Find the baseline
            verticalProfile  = sum(binaryImage, 2);
            lastLine = find(verticalProfile, 1, 'last')
            % Scan across columns finding where the top of the hump is
            for col = 1 : columns
                yy = lastLine - find(binaryImage(:, col), 1, 'first');
                if isempty(yy)
                    y(col) = 0;
                else
                    y(col) = yy;
                end
                x(col) = col;
            end
            subplot(2, 2, 3);
            plot(1 : columns, y, 'b-', 'LineWidth', 3);
            grid on;
            title('Y vs. X', 'FontSize', fontSize);
            ylabel('Y', 'FontSize', fontSize);
            xlabel('X', 'FontSize', fontSize);
            x=x/1000;
            y=y/1000;
            x=x.';
            y=y.';

            ax4 = app.UIAxes3_2;
            plot(ax4,x,y);
            xlabel(ax4,'X (in m)')
            ylabel(ax4,'Y (in m)')
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)

            cla(app.UIAxes); drawnow;
            cla(app.UIAxes2);drawnow;
            cla(app.UIAxes3);drawnow;
            cla(app.UIAxes3_2);drawnow;
            cla(app.UIAxes_2);drawnow;

            app.UIAxes.reset; drawnow;
            app.UIAxes_2.reset;drawnow;
            
            app.UIAxes_2.Visible = 'off'; drawnow;

            clear all;
            clear m;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputDataTab = uitab(app.TabGroup);
            app.InputDataTab.Title = 'Input Data';
            app.SelectaSerialRobotDropDownLabel = uilabel(app.InputDataTab);
            app.SelectaSerialRobotDropDownLabel.Text = 'Select a Serial Robot';
            app.SelectaSerialRobotDropDown = uidropdown(app.InputDataTab);
            app.SelectaSerialRobotDropDown.Items = {'Select', '6-DOF PUMA 560', '6-DOF ABB IRb-1400', '6-DOF STAUBLI RX160L', '5-DOF PIONEER-2', '5-DOF ASEA IRb-6'};
            app.SelectaSerialRobotDropDown.ItemsData = [0 1 2 3 4 5];
            app.SelectaSerialRobotDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectaSerialRobotDropDownValueChanged, true);
            app.SelectaSerialRobotDropDown.Value = 0;
            app.DHParametersStandardPanel = uipanel(app.InputDataTab);
            app.DHParametersStandardPanel.Title = 'DH Parameters - Standard';
            app.LinkNoLabel = uilabel(app.DHParametersStandardPanel);
            app.LinkNoLabel.Text = 'Link No.';
            app.LinkRotationAnglethetaindegLabel = uilabel(app.DHParametersStandardPanel);
            app.LinkRotationAnglethetaindegLabel.Text = 'Link Rotation Angle (theta) in deg';
            app.LinkOffsetdinmLabel = uilabel(app.DHParametersStandardPanel);
            app.LinkOffsetdinmLabel.Text = 'Link Offset (d) in m';
            app.LinkTwistAnglealphaindegLabel = uilabel(app.DHParametersStandardPanel);
            app.LinkTwistAnglealphaindegLabel.Text = 'Link Twist Angle (alpha) in deg';
            app.LinkLengthainmLabel = uilabel(app.DHParametersStandardPanel);
            app.LinkLengthainmLabel.Text = 'Link Length (a) in m';
            app.linknum1 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.linknum1.Value = 1;
            app.linknum2 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.linknum2.Value = 2;
            app.linknum3 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.linknum3.Value = 3;
            app.linknum4 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.linknum4.Value = 4;
            app.linknum5 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.linknum5.Value = 5;
            app.linknum6 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.linknum6.Value = 6;
            app.d1 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.d2 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.d3 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.d4 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.d5 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.d6 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.a1 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.a2 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.a3 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.a4 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.a5 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.a6 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.alpha1 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.alpha2 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.alpha3 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.alpha4 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.alpha5 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.alpha6 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.LinkRotationAngleLimitsindegreesminmaxLabel = uilabel(app.DHParametersStandardPanel);
            app.LinkRotationAngleLimitsindegreesminmaxLabel.Text = {'Link Rotation Angle Limits (in degrees) '; 'min                        max'};
            app.mintheta1 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.mintheta2 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.mintheta3 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.mintheta4 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.mintheta5 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.mintheta6 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.maxtheta1 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.maxtheta2 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.maxtheta3 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.maxtheta4 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.maxtheta5 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.maxtheta6 = uieditfield(app.DHParametersStandardPanel, 'numeric');
            app.theta1 = uieditfield(app.DHParametersStandardPanel, 'text');
            app.theta1.Value = 'q1';
            app.theta2 = uieditfield(app.DHParametersStandardPanel, 'text');
            app.theta2.Value = 'q2';
            app.theta3 = uieditfield(app.DHParametersStandardPanel, 'text');
            app.theta3.Value = 'q3';
            app.theta4 = uieditfield(app.DHParametersStandardPanel, 'text');
            app.theta4.Value = 'q4';
            app.theta5 = uieditfield(app.DHParametersStandardPanel, 'text');
            app.theta5.Value = 'q5';
            app.theta6 = uieditfield(app.DHParametersStandardPanel, 'text');
            app.theta6.Value = 'q6';
            app.StatusMessage = uitextarea(app.InputDataTab);
            app.StatusMessage.Editable = 'off';
            app.StatusMessageLabel = uilabel(app.InputDataTab);
            app.StatusMessageLabel.Text = 'Status Message';
            app.RobotAnalysisTab = uitab(app.TabGroup);
            app.RobotAnalysisTab.Title = 'Robot Analysis';
            app.RestPositionqLabel = uilabel(app.RobotAnalysisTab);
            app.RestPositionqLabel.Text = {'Initial Position '; 'Joint Variables q0 '; '(in deg)'};
            app.RestPositionq1 = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.RestPositionq2 = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.RestPositionq3 = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.RestPositionq4 = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.RestPositionq5 = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.RestPositionq6 = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.FinalPositionqLabel = uilabel(app.RobotAnalysisTab);
            app.FinalPositionqLabel.Text = {'Final Position Joint Variables qf'; '(in deg)'};
            app.FinalPositionq1 = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.FinalPositionq1.Value = -80;
            app.FinalPositionq6 = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.FinalPositionq5 = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.FinalPositionq4 = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.FinalPositionq4.Value = -10;
            app.FinalPositionq3 = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.FinalPositionq3.Value = -40;
            app.FinalPositionq2 = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.FinalPositionq2.Value = 30;
            app.RestPositionq0EditFieldLabel_3 = uilabel(app.RobotAnalysisTab);
            app.RestPositionq0EditFieldLabel_3.Text = {'Final Time tf'; '(in Sec)'};
            app.finaltime = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.finaltime.Value = 5;
            app.FinalPositionEELabel = uilabel(app.RobotAnalysisTab);
            app.FinalPositionEELabel.Text = {'Final Position of '; 'End Effector X'; '(in m)'};
            app.FinalPositionEEX = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.FinalPositionEEX.Value = 0.3;
            app.FinalPositionEEZ = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.FinalPositionEEZ.Value = 0.1;
            app.FinalPositionEEY = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.FinalPositionEEY.Value = 0.2;
            app.q1Label = uilabel(app.RobotAnalysisTab);
            app.q1Label.Text = 'q1';
            app.q2Label = uilabel(app.RobotAnalysisTab);
            app.q2Label.Text = 'q2';
            app.q3Label = uilabel(app.RobotAnalysisTab);
            app.q3Label.Text = 'q3';
            app.q4Label = uilabel(app.RobotAnalysisTab);
            app.q4Label.Text = 'q4';
            app.q5Label = uilabel(app.RobotAnalysisTab);
            app.q5Label.Text = 'q5';
            app.q6Label = uilabel(app.RobotAnalysisTab);
            app.q6Label.Text = 'q6';
            app.q1Label_2 = uilabel(app.RobotAnalysisTab);
            app.q1Label_2.Text = 'q1';
            app.q2Label_2 = uilabel(app.RobotAnalysisTab);
            app.q2Label_2.Text = 'q2';
            app.q3Label_2 = uilabel(app.RobotAnalysisTab);
            app.q3Label_2.Text = 'q3';
            app.q4Label_2 = uilabel(app.RobotAnalysisTab);
            app.q4Label_2.Text = 'q4';
            app.q5Label_2 = uilabel(app.RobotAnalysisTab);
            app.q5Label_2.Text = 'q5';
            app.q6Label_2 = uilabel(app.RobotAnalysisTab);
            app.q6Label_2.Text = 'q6';
            app.t_fLabel = uilabel(app.RobotAnalysisTab);
            app.t_fLabel.Text = 't_f';
            app.XLabel = uilabel(app.RobotAnalysisTab);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.RobotAnalysisTab);
            app.YLabel.Text = 'Y';
            app.ZLabel = uilabel(app.RobotAnalysisTab);
            app.ZLabel.Text = 'Z';
            app.RobotPlotPanel = uipanel(app.RobotAnalysisTab);
            app.RobotPlotPanel.Title = 'Robot Plot';
            app.UIAxes = uiaxes(app.RobotPlotPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RobotPlotPanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.SetTimeSliderLabel = uilabel(app.RobotAnalysisTab);
            app.SetTimeSliderLabel.Text = 'Set Time';
            app.SetTimeSlider = uislider(app.RobotAnalysisTab);
            app.SetTimeSlider.Limits = [0 50];
            app.SetTimeSlider.ValueChangingFcn = createCallbackFcn(app, @SetTimeSliderValueChanging, true);
            app.RobotAnalysisPanel = uipanel(app.RobotAnalysisTab);
            app.RobotAnalysisPanel.Title = 'Robot Analysis';
            app.ForwardKinematicsButton = uibutton(app.RobotAnalysisPanel, 'push');
            app.ForwardKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardKinematicsButtonPushed, true);
            app.ForwardKinematicsButton.Text = 'Forward Kinematics';
            app.BuildRobotButton = uibutton(app.RobotAnalysisPanel, 'push');
            app.BuildRobotButton.ButtonPushedFcn = createCallbackFcn(app, @BuildRobotButtonPushed, true);
            app.BuildRobotButton.Text = 'Build Robot';
            app.InverseKinematicsButton = uibutton(app.RobotAnalysisPanel, 'push');
            app.InverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @InverseKinematicsButtonPushed, true);
            app.InverseKinematicsButton.Text = 'Inverse Kinematics';
            app.TraceTrajectoryButton = uibutton(app.RobotAnalysisPanel, 'push');
            app.TraceTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @TraceTrajectoryButtonPushed, true);
            app.TraceTrajectoryButton.Text = 'Trace Trajectory';
            app.ForKinX = uieditfield(app.RobotAnalysisPanel, 'numeric');
            app.ForKinZ = uieditfield(app.RobotAnalysisPanel, 'numeric');
            app.ForKinY = uieditfield(app.RobotAnalysisPanel, 'numeric');
            app.XLabel_2 = uilabel(app.RobotAnalysisPanel);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.RobotAnalysisPanel);
            app.YLabel_2.Text = 'Y';
            app.ZLabel_2 = uilabel(app.RobotAnalysisPanel);
            app.ZLabel_2.Text = 'Z';
            app.InvKinq1 = uieditfield(app.RobotAnalysisPanel, 'numeric');
            app.InvKinq2 = uieditfield(app.RobotAnalysisPanel, 'numeric');
            app.InvKinq3 = uieditfield(app.RobotAnalysisPanel, 'numeric');
            app.InvKinq4 = uieditfield(app.RobotAnalysisPanel, 'numeric');
            app.InvKinq5 = uieditfield(app.RobotAnalysisPanel, 'numeric');
            app.InvKinq6 = uieditfield(app.RobotAnalysisPanel, 'numeric');
            app.q1Label_3 = uilabel(app.RobotAnalysisPanel);
            app.q1Label_3.Text = 'q1';
            app.q2Label_3 = uilabel(app.RobotAnalysisPanel);
            app.q2Label_3.Text = 'q2';
            app.q3Label_3 = uilabel(app.RobotAnalysisPanel);
            app.q3Label_3.Text = 'q3';
            app.q4Label_3 = uilabel(app.RobotAnalysisPanel);
            app.q4Label_3.Text = 'q4';
            app.q5Label_3 = uilabel(app.RobotAnalysisPanel);
            app.q5Label_3.Text = 'q5';
            app.q6Label_3 = uilabel(app.RobotAnalysisPanel);
            app.q6Label_3.Text = 'q6';
            app.WorkspaceButton = uibutton(app.RobotAnalysisPanel, 'push');
            app.WorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @WorkspaceButtonPushed, true);
            app.WorkspaceButton.Text = 'Workspace';
            app.StaticAnalysisButton = uibutton(app.RobotAnalysisPanel, 'push');
            app.StaticAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StaticAnalysisButtonPushed, true);
            app.StaticAnalysisButton.Text = 'Static Analysis';
            app.DynamicAnalysisButton = uibutton(app.RobotAnalysisPanel, 'push');
            app.DynamicAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @DynamicAnalysisButtonPushed, true);
            app.DynamicAnalysisButton.Text = 'Dynamic Analysis';
            app.torque6 = uieditfield(app.RobotAnalysisPanel, 'numeric');
            app.Tor6Label_3 = uilabel(app.RobotAnalysisPanel);
            app.Tor6Label_3.Text = 'Tor6';
            app.torque5 = uieditfield(app.RobotAnalysisPanel, 'numeric');
            app.torque4 = uieditfield(app.RobotAnalysisPanel, 'numeric');
            app.Tor4Label_3 = uilabel(app.RobotAnalysisPanel);
            app.Tor4Label_3.Text = 'Tor4';
            app.torque3 = uieditfield(app.RobotAnalysisPanel, 'numeric');
            app.Tor3Label_3 = uilabel(app.RobotAnalysisPanel);
            app.Tor3Label_3.Text = 'Tor3';
            app.Tor5Label_3 = uilabel(app.RobotAnalysisPanel);
            app.Tor5Label_3.Text = 'Tor5';
            app.Tor2Label_3 = uilabel(app.RobotAnalysisPanel);
            app.Tor2Label_3.Text = 'Tor2';
            app.torque2 = uieditfield(app.RobotAnalysisPanel, 'numeric');
            app.torque1 = uieditfield(app.RobotAnalysisPanel, 'numeric');
            app.Tor1Label_3 = uilabel(app.RobotAnalysisPanel);
            app.Tor1Label_3.Text = 'Tor1';
            app.ForceLabel = uilabel(app.RobotAnalysisTab);
            app.ForceLabel.Text = 'Force on End Effector F (in N)';
            app.fxval = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.fxval.Value = 5;
            app.fzval = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.fzval.Value = 3;
            app.fyval = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.fyval.Value = 4;
            app.FxLabel = uilabel(app.RobotAnalysisTab);
            app.FxLabel.Text = 'Fx';
            app.FyLabel = uilabel(app.RobotAnalysisTab);
            app.FyLabel.Text = 'Fy';
            app.FzLabel = uilabel(app.RobotAnalysisTab);
            app.FzLabel.Text = 'Fz';
            app.MomentLabel = uilabel(app.RobotAnalysisTab);
            app.MomentLabel.Text = 'Moment on End Effector n (in N.m)';
            app.nxval = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.nxval.Value = 0.1;
            app.nzval = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.nzval.Value = 0.1;
            app.nyval = uieditfield(app.RobotAnalysisTab, 'numeric');
            app.nyval.Value = 0.1;
            app.nxLabel = uilabel(app.RobotAnalysisTab);
            app.nxLabel.Text = 'nx';
            app.nyLabel = uilabel(app.RobotAnalysisTab);
            app.nyLabel.Text = 'ny';
            app.nzLabel = uilabel(app.RobotAnalysisTab);
            app.nzLabel.Text = 'nz';
            app.HandMovementTab = uitab(app.TabGroup);
            app.HandMovementTab.Title = 'Hand Movement';
            app.UIAxes2 = uiaxes(app.HandMovementTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [-100 100];
            app.UIAxes2.YLim = [-100 100];
            app.StartSimulationButton = uibutton(app.HandMovementTab, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.TimetoRunsecondsEditFieldLabel = uilabel(app.HandMovementTab);
            app.TimetoRunsecondsEditFieldLabel.Text = 'Time to Run (seconds)';
            app.TimetoRunsecondsEditField = uieditfield(app.HandMovementTab, 'numeric');
            app.TimetoRunsecondsEditField.Value = 50;
            app.StatusMessage_2 = uitextarea(app.HandMovementTab);
            app.StatusMessage_2.Editable = 'off';
            app.StatusMessageLabel_2 = uilabel(app.HandMovementTab);
            app.StatusMessageLabel_2.Text = 'Status Message';
            app.timerEditField = uieditfield(app.HandMovementTab, 'numeric');
            app.timerEditField.RoundFractionalValues = 'on';
            app.SolidModelsTab = uitab(app.TabGroup);
            app.SolidModelsTab.Title = 'Solid  Models';
            app.UIAxes3 = uiaxes(app.SolidModelsTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_3 = uiaxes(app.SolidModelsTab);
            title(app.UIAxes3_3, 'Title')
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            zlabel(app.UIAxes3_3, 'Z')
            app.WorkpieceFileEditFieldLabel = uilabel(app.SolidModelsTab);
            app.WorkpieceFileEditFieldLabel.Text = 'Workpiece File';
            app.WorkpieceFileEditField = uieditfield(app.SolidModelsTab, 'text');
            app.WorkpieceFileEditField.Value = 'Part1.sldprt';
            app.FinishedPartFileEditFieldLabel = uilabel(app.SolidModelsTab);
            app.FinishedPartFileEditFieldLabel.Text = 'Finished Part File';
            app.FinishedPartFileEditField = uieditfield(app.SolidModelsTab, 'text');
            app.FinishedPartFileEditField.Value = 'Part1_finish.sldprt';
            app.StartButton = uibutton(app.SolidModelsTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Image = uiimage(app.SolidModelsTab);
            app.Image.ImageSource = 'Capture1.JPG';
            app.Image_2 = uiimage(app.SolidModelsTab);
            app.Image_2.ImageSource = 'Capture.JPG';
            app.ImageRecognitionTab = uitab(app.TabGroup);
            app.ImageRecognitionTab.Title = 'Image Recognition';
            app.UIAxes3_2 = uiaxes(app.ImageRecognitionTab);
            title(app.UIAxes3_2, 'Title')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.StartButton_2 = uibutton(app.ImageRecognitionTab, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.ImageFileEditFieldLabel = uilabel(app.ImageRecognitionTab);
            app.ImageFileEditFieldLabel.Text = 'Image File';
            app.ImageFileEditField = uieditfield(app.ImageRecognitionTab, 'text');
            app.ImageFileEditField.Value = 'part1.jpg';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end